classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ToolbarGrid                    matlab.ui.container.GridLayout
        tool_LeftPanelVisibility       matlab.ui.control.Image
        DocumentGrid                   matlab.ui.container.GridLayout
        Folders_Grid                   matlab.ui.container.GridLayout
        config_FolderMapPanel          matlab.ui.container.Panel
        config_FolderMapGrid           matlab.ui.container.GridLayout
        config_Folder_tempPath         matlab.ui.control.EditField
        config_Folder_tempPathLabel    matlab.ui.control.Label
        config_Folder_userPathButton   matlab.ui.control.Image
        config_Folder_userPath         matlab.ui.control.EditField
        config_Folder_userPathLabel    matlab.ui.control.Label
        config_FolderMapLabel          matlab.ui.control.Label
        plot_Grid                      matlab.ui.container.GridLayout
        plot_IntegrationPanel          matlab.ui.container.Panel
        plot_IntegrationGrid           matlab.ui.container.GridLayout
        plot_IntegrationTime           matlab.ui.control.NumericEditField
        plot_IntegrationTimeLabel      matlab.ui.control.Label
        plot_IntegrationTrace          matlab.ui.control.NumericEditField
        plot_IntegrationTraceLabel     matlab.ui.control.Label
        plot_IntegrationLabel          matlab.ui.control.Label
        plot_WaterfallPanel            matlab.ui.container.Panel
        plot_WaterfallGrid             matlab.ui.container.GridLayout
        plot_WaterfallDepth            matlab.ui.control.DropDown
        plot_WaterfallDepthLabel       matlab.ui.control.Label
        plot_WaterfallColormap         matlab.ui.control.DropDown
        plot_WaterfallColormapLabel    matlab.ui.control.Label
        plot_WaterfallLabel            matlab.ui.control.Label
        plot_colorsPanel               matlab.ui.container.Panel
        plot_colorsGrid                matlab.ui.container.GridLayout
        plot_colorsClearWrite          matlab.ui.control.ColorPicker
        plot_colorsClearWriteLabel     matlab.ui.control.Label
        plot_colorsMaxHold             matlab.ui.control.ColorPicker
        plot_colorsMaxHoldLabel        matlab.ui.control.Label
        plot_colorsAverage             matlab.ui.control.ColorPicker
        plot_colorsAverageLabel        matlab.ui.control.Label
        plot_colorsMinHold             matlab.ui.control.ColorPicker
        plot_colorsMinHoldLabel        matlab.ui.control.Label
        plot_colorsLabel               matlab.ui.control.Label
        plot_TiledSpacing              matlab.ui.control.DropDown
        plot_TiledSpacingLabel         matlab.ui.control.Label
        plot_InteractionsPanel         matlab.ui.container.Panel
        plot_InteractionsGrid          matlab.ui.container.GridLayout
        plot_RestoreViewVisibility     matlab.ui.control.Image
        plot_RestoreView               matlab.ui.control.Image
        plot_ZoomOutVisibility         matlab.ui.control.Image
        plot_ZoomOut                   matlab.ui.control.Image
        plot_ZoomInVisibility          matlab.ui.control.Image
        plot_ZoomIn                    matlab.ui.control.Image
        plot_PanVisibility             matlab.ui.control.Image
        plot_Pan                       matlab.ui.control.Image
        plot_DatatipVisibility         matlab.ui.control.Image
        plot_Datatip                   matlab.ui.control.Image
        plot_refresh                   matlab.ui.control.Image
        plot_InteractionsLabel         matlab.ui.control.Label
        plot_Title                     matlab.ui.control.Label
        general_Grid                   matlab.ui.container.GridLayout
        general_GraphicsPanel          matlab.ui.container.Panel
        general_GraphicsGrid           matlab.ui.container.GridLayout
        openAuxiliarApp2Debug          matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked        matlab.ui.control.CheckBox
        gpuType                        matlab.ui.control.DropDown
        gpuTypeLabel                   matlab.ui.control.Label
        general_GraphicsLabel          matlab.ui.control.Label
        general_versionPanel           matlab.ui.container.Panel
        server_Grid                    matlab.ui.container.GridLayout
        server_Port                    matlab.ui.control.NumericEditField
        server_PortLabel               matlab.ui.control.Label
        server_IP                      matlab.ui.control.EditField
        server_IPLabel                 matlab.ui.control.Label
        server_ClientList              matlab.ui.control.EditField
        server_ClientListLabel         matlab.ui.control.Label
        server_Key                     matlab.ui.control.EditField
        server_KeyLabel                matlab.ui.control.Label
        server_Status                  matlab.ui.control.DropDown
        server_StatusLabel             matlab.ui.control.Label
        general_versionLock            matlab.ui.control.Image
        general_versionLabel           matlab.ui.control.Label
        general_FilePanel              matlab.ui.container.Panel
        general_stationGrid            matlab.ui.container.GridLayout
        general_lastSessionInfo        matlab.ui.control.CheckBox
        general_stationLongitude       matlab.ui.control.NumericEditField
        general_stationLongitudeLabel  matlab.ui.control.Label
        general_stationLatitude        matlab.ui.control.NumericEditField
        general_stationLatitudeLabel   matlab.ui.control.Label
        general_stationType            matlab.ui.control.DropDown
        general_stationTypeLabel       matlab.ui.control.Label
        general_stationName            matlab.ui.control.EditField
        general_stationNameLabel       matlab.ui.control.Label
        general_FileLock               matlab.ui.control.Image
        general_FileLabel              matlab.ui.control.Label
        general_AppVersionPanel        matlab.ui.container.Panel
        general_AppVersionGrid         matlab.ui.container.GridLayout
        AppVersion                     matlab.ui.control.HTML
        general_AppVersionRefresh      matlab.ui.control.Image
        general_AppVersionLabel        matlab.ui.control.Label
        LeftPanel_Grid                 matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LeftPanelGrid                  matlab.ui.container.GridLayout
        LeftPanelRadioGroup            matlab.ui.container.ButtonGroup
        btnFolder                      matlab.ui.control.RadioButton
        btnPlot                        matlab.ui.control.RadioButton
        btnGeneral                     matlab.ui.control.RadioButton
        Tab1_GridTitle                 matlab.ui.container.GridLayout
        menu_ButtonLabel               matlab.ui.control.Label
        menu_ButtonIcon                matlab.ui.control.Image
    end

    
    properties
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        
        CallingApp
        rootFolder
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        function AppProperties(app)
            app.general_FileLock.UserData    = true;
            app.general_versionLock.UserData = true;

            if isdeployed
                app.openAuxiliarApp2Debug.Enable = 0;
            end
        end

        %-----------------------------------------------------------------%
        function Layout(app)
            general_updateLayout(app)

            plot_updateLayout_Interactions(app)
            plot_updateLayout_TiledSpacing(app)
            plot_updateLayout_OthersFields(app)
            
            config_updatePanel(app)
        end

        %-----------------------------------------------------------------%
        function general_updateLayout(app)
            % PAINEL "ASPECTOS GERAIS"
            % (a) Versão
            general_AppVersionRefreshImageClicked(app)

            % (b) Estação
            app.general_stationName.Value        = app.CallingApp.General.stationInfo.Name;
            app.general_stationType.Items        = {app.CallingApp.General.stationInfo.Type};
            app.general_stationLatitude.Value    = app.CallingApp.General.stationInfo.Latitude;
            app.general_stationLongitude.Value   = app.CallingApp.General.stationInfo.Longitude;
            app.general_lastSessionInfo.Value    = app.CallingApp.General.startupInfo;

            % (c) WebService
            switch app.CallingApp.General.tcpServer.Status
                case 1; app.server_Status.Value  = 'ON';
                case 0; app.server_Status.Value  = 'OFF';                
            end
            app.server_Key.Value                 = app.CallingApp.General.tcpServer.Key;
            app.server_ClientList.Value          = strjoin(app.CallingApp.General.tcpServer.ClientList, ', ');
            app.server_IP.Value                  = app.CallingApp.General.tcpServer.IP;
            app.server_Port.Value                = app.CallingApp.General.tcpServer.Port;
            
            % (d) Gráfico: RENDERIZADOR
            graphRender = opengl('data');
            switch graphRender.HardwareSupportLevel
                case 'basic'; graphRenderSupport = 'hardwarebasic';
                case 'full';  graphRenderSupport = 'hardware';
                case 'none';  graphRenderSupport = 'software';
                otherwise;    graphRenderSupport = graphRender.HardwareSupportLevel; % "driverissue"
            end

            if ~ismember(graphRenderSupport, app.gpuType.Items)
                app.gpuType.Items{end+1} = graphRenderSupport;
            end

            app.gpuType.Value = graphRenderSupport;

            % (e) Gráfico: MODO DE OPERAÇÃO
            app.openAuxiliarAppAsDocked.Value = app.CallingApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value   = app.CallingApp.General.operationMode.Debug;
        end

        %-----------------------------------------------------------------%
        function plot_updateLayout_Interactions(app)
            set(findobj(app.plot_InteractionsGrid, Tag='InteractionVisibility'), Visible=0)

            for ii = 1:numel(app.CallingApp.axes1.Toolbar.Children)
                switch app.CallingApp.axes1.Toolbar.Children(ii).Tag
                    case 'datacursor';  h = app.plot_DatatipVisibility;
                    case 'pan';         h = app.plot_PanVisibility;
                    case 'zoomin';      h = app.plot_ZoomInVisibility;
                    case 'zoomout';     h = app.plot_ZoomOutVisibility;
                    case 'restoreview'; h = app.plot_RestoreViewVisibility;
                end

                if app.CallingApp.axes1.Toolbar.Children(ii).Visible
                    h.Visible = 1;
                end
            end
        end

        %-----------------------------------------------------------------%
        function plot_updateLayout_TiledSpacing(app)
            app.plot_TiledSpacing.Value = app.CallingApp.axes1.Parent.TileSpacing;
        end

        %-----------------------------------------------------------------%
        function plot_updateLayout_OthersFields(app)
            app.plot_colorsMinHold.Value     = app.CallingApp.General.Plot.MinHold.Color;
            app.plot_colorsAverage.Value     = app.CallingApp.General.Plot.Average.Color;
            app.plot_colorsMaxHold.Value     = app.CallingApp.General.Plot.MaxHold.Color;
            app.plot_colorsClearWrite.Value  = app.CallingApp.General.Plot.ClearWrite.Color;
            
            app.plot_WaterfallColormap.Items = unique([app.plot_WaterfallColormap.Items, {app.CallingApp.General.Plot.Waterfall.Colormap}]);
            app.plot_WaterfallColormap.Value = app.CallingApp.General.Plot.Waterfall.Colormap;

            app.plot_WaterfallDepth.Items    = unique([app.plot_WaterfallDepth.Items, {num2str(app.CallingApp.General.Plot.Waterfall.Depth)}], 'stable');
            app.plot_WaterfallDepth.Value    = {num2str(app.CallingApp.General.Plot.Waterfall.Depth)};

            app.plot_IntegrationTrace.Value  = app.CallingApp.General.Integration.Trace;
            app.plot_IntegrationTime.Value   = app.CallingApp.General.Integration.SampleTime;
        end

        %-----------------------------------------------------------------%
        function config_updatePanel(app)
            % Na versão webapp, a configuração das pastas não é habilitada.

            switch app.CallingApp.executionMode
                case 'webApp'
                    app.btnFolder.Enable = 0;

                otherwise    
                    % userPath & tempPath
                    app.config_Folder_userPath.Value = app.CallingApp.General.fileFolder.userPath;
                    app.config_Folder_tempPath.Value = app.CallingApp.General.fileFolder.tempPath;
            end
        end

        %-----------------------------------------------------------------%
        function status = IPv4Validation(app, ipAddress)
            status   = true;
            ipString = regexp(ipAddress, '\d*[.]{1}\d{1,3}[.]{1}\d{1,3}[.]{1}\d*', 'match');
            
            if isempty(ipString)
                status = false;
            else
                ipArray = cellfun(@(x) str2double(x), strsplit(char(ipString), '.'));
                if any(ipArray > 255) || any(isnan(ipArray))
                    status = false;
                end                    
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appUtil.generalSettingsSave(class.Constants.appName, app.rootFolder, app.CallingApp.General_I, app.CallingApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % de algumas das informações organizadas em "GeneralSettings.json".            
            app.CallingApp = mainapp;
            app.rootFolder = app.CallingApp.rootFolder;

            if app.isDocked
                app.GridLayout.Padding(4) = 21;
            else
                appUtil.winPosition(app.UIFigure)
            end

            AppProperties(app)
            Layout(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            appBackDoor(app.CallingApp, app, 'closeFcn', 'CONFIG')
            delete(app)
            
        end

        % Selection changed function: LeftPanelRadioGroup
        function LeftPanelRadioGroupSelectionChanged(app, event)

            selectedButton = app.LeftPanelRadioGroup.SelectedObject;
            switch selectedButton
                case app.btnGeneral; app.DocumentGrid.ColumnWidth(2:4) = {'1x',0,0};
                case app.btnPlot;    app.DocumentGrid.ColumnWidth(2:4) = {0,'1x',0};
                case app.btnFolder;  app.DocumentGrid.ColumnWidth(2:4) = {0,0,'1x'};
            end

        end

        % Image clicked function: tool_LeftPanelVisibility
        function tool_LeftPanelVisibilityImageClicked(app, event)
            
            if app.DocumentGrid.ColumnWidth{1}
                app.DocumentGrid.ColumnWidth{1} = 0;
                app.tool_LeftPanelVisibility.ImageSource = 'ArrowRight_32.png';
            else
                app.DocumentGrid.ColumnWidth{1} = 325;
                app.tool_LeftPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            end

        end

        % Image clicked function: general_AppVersionRefresh
        function general_AppVersionRefreshImageClicked(app, event)
            
            htmlContent = auxApp.config.htmlCode_AppVersion(app.CallingApp.General, app.CallingApp.executionMode);
            app.AppVersion.HTMLSource = htmlContent;

        end

        % Value changed function: general_lastSessionInfo, 
        % ...and 12 other components
        function general_ParameterChanged(app, event)
            
            switch event.Source
                case app.general_stationName
                    if isempty(regexp(app.general_stationName.Value, '^EMSat$|^UMS.*|^ERMx-[A-Z]{2}-[0-9][1-9]$', 'once'))
                        msgQuestion   = ['O nome esperado de uma estação é <b>"ERMx-UF-XX"</b>, sendo "UF" a sigla da unidade da '     ...
                                         'federação e XX dois dígitos numéricos (01 a 99). Além disso, são previstas inclusões '       ...
                                         'de nomes de estações iniciando com <b>"UMS</b>" ou <b>"EMSat"</b>.<br><br>O nome inserido, ' ...
                                         'contudo, difere dessas opções. Deseja continuar?'];
                        userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
                            if strcmp(userSelection, 'Não')
                                app.general_stationName.Value = event.PreviousValue;
                                return
                            end
                    end

                    app.CallingApp.General.stationInfo.Name      = app.general_stationName.Value;

                case app.general_stationType
                    app.CallingApp.General.stationInfo.Type      = app.general_stationType.Value;

                case app.general_stationLatitude
                    app.CallingApp.General.stationInfo.Latitude  = app.general_stationLatitude.Value;

                case app.general_stationLongitude
                    app.CallingApp.General.stationInfo.Longitude = app.general_stationLongitude.Value;

                case app.general_lastSessionInfo
                    app.CallingApp.General.startupInfo           = app.general_lastSessionInfo.Value;                    

                case app.server_Status
                    switch app.server_Status.Value
                        case 'ON';  app.CallingApp.General.tcpServer.Status = 1;
                        case 'OFF'; app.CallingApp.General.tcpServer.Status = 0;
                    end

                case app.server_Key
                    app.server_Key.Value = replace(app.server_Key.Value, ' ', '');
                    app.CallingApp.General.tcpServer.Key = app.server_Key.Value;

                case app.server_ClientList
                    app.server_ClientList.Value = replace(app.server_ClientList.Value, ' ', '');
                    
                    if isempty(app.server_ClientList.Value)
                        app.CallingApp.General.tcpServer.ClientList = {};
                    else
                        app.CallingApp.General.tcpServer.ClientList = strsplit(app.server_ClientList.Value, ',');
                    end

                    app.server_ClientList.Value = strjoin(app.CallingApp.General.tcpServer.ClientList, ', ');

                case app.server_IP
                    app.server_IP.Value = strtrim(app.server_IP.Value);

                    if IPv4Validation(app, app.server_IP.Value) || isempty(app.server_IP.Value)
                        app.CallingApp.General.tcpServer.IP = app.server_IP.Value;
                    else
                        app.server_IP.Value = event.PreviousValue;
                        appUtil.modalWindow(app.UIFigure, 'warning', 'Endereço inválido (IPv4).');
                    end

                case app.server_Port
                    app.CallingApp.General.tcpServer.Port = app.server_Port.Value;

                case app.gpuType
                    if ismember(app.gpuType.Value, {'hardwarebasic', 'hardware', 'software'})
                        app.CallingApp.General.openGL = app.gpuType.Value;
                    end

                case app.openAuxiliarAppAsDocked
                    app.CallingApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.CallingApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;
            end

            app.CallingApp.General_I.operationMode = app.CallingApp.General.operationMode;
            app.CallingApp.General_I.stationInfo   = app.CallingApp.General.stationInfo;
            app.CallingApp.General_I.openGL        = app.CallingApp.General.openGL;
            app.CallingApp.General_I.startupInfo   = app.CallingApp.General.startupInfo;
            app.CallingApp.General_I.tcpServer     = app.CallingApp.General.tcpServer;

            saveGeneralSettings(app)
            general_updateLayout(app)
            
        end

        % Image clicked function: plot_refresh
        function plot_RefreshImageClicked(app, event)
            
            % Axes toolbar
            Interactions = class.Constants.Interactions;
            plotFcn.axesInteractions(app.CallingApp.axes1, Interactions)
            plotFcn.axesInteractions(app.CallingApp.axes2, Interactions)

            app.CallingApp.axes1.Parent.TileSpacing        = 'tight';

            % Others parameters...
            app.CallingApp.General.Plot.MinHold.Color      = '#4A90E2';
            app.CallingApp.General.Plot.ClearWrite.Color   = '#ffff12';
            app.CallingApp.General.Plot.Average.Color      = '#00cc66';
            app.CallingApp.General.Plot.MaxHold.Color      = '#FF5CAD';

            app.CallingApp.General.Plot.Waterfall.Colormap = 'hot';
            colormap(app.CallingApp.axes2, 'hot')
            app.CallingApp.General.Plot.Waterfall.Depth    = 64;            
            app.CallingApp.General.Integration             = struct('Trace', 10, 'SampleTime', 10);

            app.CallingApp.General_I.Plot        = app.CallingApp.General.Plot;
            app.CallingApp.General_I.Integration = app.CallingApp.General.Integration;
            saveGeneralSettings(app)

            plot_updateLayout_Interactions(app)
            plot_updateLayout_TiledSpacing(app)
            plot_updateLayout_OthersFields(app)

        end

        % Image clicked function: plot_Datatip, plot_Pan, 
        % ...and 3 other components
        function plot_AxesInteractionsChanged(app, event)
            
            % Interações atuais:
            for ii = 1:numel(app.CallingApp.axes1.Toolbar.Children)
                if isprop(app.CallingApp.axes1.Toolbar.Children(ii), 'Value') && app.CallingApp.axes1.Toolbar.Children(ii).Value
                    appUtil.modalWindow(app.UIFigure, 'warning', 'Operação não realizada! É preciso desabilitar qualquer interação com os plots...');
                    return
                end
            end

            % Interações desejadas:
            switch event.Source
                case app.plot_Datatip;     h = app.plot_DatatipVisibility;
                case app.plot_Pan;         h = app.plot_PanVisibility;
                case app.plot_ZoomIn;      h = app.plot_ZoomInVisibility;
                case app.plot_ZoomOut;     h = app.plot_ZoomOutVisibility;
                case app.plot_RestoreView; h = app.plot_RestoreViewVisibility;
            end
            h.Visible = ~h.Visible;
            
            finalInteractions = {};
            if app.plot_DatatipVisibility.Visible;     finalInteractions = [finalInteractions, 'datacursor' ]; end
            if app.plot_PanVisibility.Visible;         finalInteractions = [finalInteractions, 'pan'        ]; end
            if app.plot_ZoomInVisibility.Visible;      finalInteractions = [finalInteractions, 'zoomin'     ]; end
            if app.plot_ZoomOutVisibility.Visible;     finalInteractions = [finalInteractions, 'zoomout'    ]; end
            if app.plot_RestoreViewVisibility.Visible; finalInteractions = [finalInteractions, 'restoreview']; end

            plotFcn.axesInteractions(app.CallingApp.axes1, finalInteractions)
            plotFcn.axesInteractions(app.CallingApp.axes2, finalInteractions)

            if isempty(finalInteractions)
                app.CallingApp.axes1.PickableParts = 'none';
                app.CallingApp.axes2.PickableParts = 'none';
            else
                app.CallingApp.axes1.PickableParts = 'visible';
                app.CallingApp.axes2.PickableParts = 'visible';
            end

            plot_updateLayout_Interactions(app)

        end

        % Value changed function: plot_TiledSpacing
        function plot_AxesTiledSpacingChanged(app, event)
            
            app.CallingApp.axes1.Parent.TileSpacing = app.plot_TiledSpacing.Value;
            
        end

        % Callback function: plot_colorsAverage, plot_colorsClearWrite, 
        % ...and 2 other components
        function plot_ColorParameterChanged(app, event)
            
            initialColor  = event.PreviousValue;
            selectedColor = event.Value;

            if ~isequal(initialColor, selectedColor)
                selectedColor = rgb2hex(selectedColor);
    
                switch event.Source
                    case app.plot_colorsMinHold
                        app.CallingApp.General.Plot.MinHold.Color    = selectedColor;
                    case app.plot_colorsAverage
                        app.CallingApp.General.Plot.Average.Color    = selectedColor;
                    case app.plot_colorsMaxHold
                        app.CallingApp.General.Plot.MaxHold.Color    = selectedColor;
                    case app.plot_colorsClearWrite
                        app.CallingApp.General.Plot.ClearWrite.Color = selectedColor;
                end
            end

            app.CallingApp.General_I.Plot = app.CallingApp.General.Plot;
            saveGeneralSettings(app)

        end

        % Value changed function: plot_IntegrationTime, 
        % ...and 3 other components
        function plot_OthersParameterChanged(app, event)
            
            switch event.Source
                case app.plot_WaterfallColormap
                    colormap(app.CallingApp.axes2, app.plot_WaterfallColormap.Value)
                    app.CallingApp.General.Plot.Waterfall.Colormap = app.plot_WaterfallColormap.Value;

                case app.plot_WaterfallDepth
                    app.CallingApp.General.Plot.Waterfall.Depth    = str2double(app.plot_WaterfallDepth.Value);
                
                case app.plot_IntegrationTrace
                    app.CallingApp.General.Integration.Trace       = app.plot_IntegrationTrace.Value;

                case app.plot_IntegrationTime
                    app.CallingApp.General.Integration.SampleTime  = app.plot_IntegrationTime.Value;
            end

            app.CallingApp.General_I.Plot        = app.CallingApp.General.Plot;
            app.CallingApp.General_I.Integration = app.CallingApp.General.Integration;
            saveGeneralSettings(app)

        end

        % Image clicked function: general_FileLock, general_versionLock
        function general_PanelLockControl(app, event)
            
            switch event.Source
                case app.general_FileLock
                    gridContainer = app.general_stationGrid;
                case app.general_versionLock
                    gridContainer = app.server_Grid;
            end

            event.Source.UserData = ~event.Source.UserData;
            if event.Source.UserData
                event.Source.ImageSource = 'lockClose_32.png';
                set(findobj(gridContainer.Children, '-not', 'Type', 'uilabel'), 'Enable', 0)
            else
                event.Source.ImageSource = 'lockOpen_32.png';
                set(findobj(gridContainer.Children, '-not', 'Type', 'uilabel'), 'Enable', 1)
            end

        end

        % Image clicked function: config_Folder_userPathButton
        function config_getFolder(app, event)
            
            try
                relatedFolder = eval(sprintf('app.config_Folder_%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.CallingApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.config_Folder_userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            figure(app.UIFigure)

            % Por enquanto, aplicável ao appColeta apenas o "userPath". Caso o 
            % app seja evoluído, consumindo base de dados do Sharepoint, por 
            % exemplo, deve-se copiar esse callback inserido no appAnalise.

            if selectedFolder
                switch event.Source
                    case app.config_Folder_userPathButton
                        app.config_Folder_userPath.Value = selectedFolder;
                        app.CallingApp.General.fileFolder.userPath = selectedFolder;

                    otherwise
                        error('Unexpected call')
                end

                app.CallingApp.General_I.fileFolder = app.CallingApp.General.fileFolder;
                saveGeneralSettings(app)
                config_updatePanel(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DocumentGrid = uigridlayout(app.GridLayout);
            app.LeftPanel_Grid = uigridlayout(app.DocumentGrid);
            app.Tab1_GridTitle = uigridlayout(app.LeftPanel_Grid);
            app.menu_ButtonIcon = uiimage(app.Tab1_GridTitle);
            app.menu_ButtonIcon.Tag = '1';
            app.menu_ButtonIcon.ImageSource = 'Settings_18.png';
            app.menu_ButtonLabel = uilabel(app.Tab1_GridTitle);
            app.menu_ButtonLabel.Text = 'CONFIGURAÇÕES';
            app.LeftPanel = uipanel(app.LeftPanel_Grid);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.LeftPanelRadioGroup = uibuttongroup(app.LeftPanelGrid);
            app.LeftPanelRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @LeftPanelRadioGroupSelectionChanged, true);
            app.btnGeneral = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnGeneral.Text = 'Aspectos gerais';
            app.btnGeneral.Value = true;
            app.btnPlot = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnPlot.Text = 'Customização do plot';
            app.btnFolder = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnFolder.Text = 'Mapeamento de pastas';
            app.general_Grid = uigridlayout(app.DocumentGrid);
            app.general_AppVersionLabel = uilabel(app.general_Grid);
            app.general_AppVersionLabel.Text = 'ASPECTOS GERAIS';
            app.general_AppVersionRefresh = uiimage(app.general_Grid);
            app.general_AppVersionRefresh.ImageClickedFcn = createCallbackFcn(app, @general_AppVersionRefreshImageClicked, true);
            app.general_AppVersionRefresh.Tooltip = {'Verifica atualizações'};
            app.general_AppVersionRefresh.ImageSource = 'Refresh_18.png';
            app.general_AppVersionPanel = uipanel(app.general_Grid);
            app.general_AppVersionGrid = uigridlayout(app.general_AppVersionPanel);
            app.AppVersion = uihtml(app.general_AppVersionGrid);
            app.AppVersion.HTMLSource = ' ';
            app.general_FileLabel = uilabel(app.general_Grid);
            app.general_FileLabel.Text = 'ESTAÇÃO';
            app.general_FileLock = uiimage(app.general_Grid);
            app.general_FileLock.ImageClickedFcn = createCallbackFcn(app, @general_PanelLockControl, true);
            app.general_FileLock.ImageSource = 'lockClose_32.png';
            app.general_FilePanel = uipanel(app.general_Grid);
            app.general_stationGrid = uigridlayout(app.general_FilePanel);
            app.general_stationNameLabel = uilabel(app.general_stationGrid);
            app.general_stationNameLabel.Text = 'Nome:';
            app.general_stationName = uieditfield(app.general_stationGrid, 'text');
            app.general_stationName.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_stationName.Enable = 'off';
            app.general_stationTypeLabel = uilabel(app.general_stationGrid);
            app.general_stationTypeLabel.Text = 'Tipo:';
            app.general_stationType = uidropdown(app.general_stationGrid);
            app.general_stationType.Items = {'Fixed', 'Mobile'};
            app.general_stationType.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_stationType.Enable = 'off';
            app.general_stationType.Value = 'Fixed';
            app.general_stationLatitudeLabel = uilabel(app.general_stationGrid);
            app.general_stationLatitudeLabel.Text = {'Latitude:'; '(graus decimais)'};
            app.general_stationLatitude = uieditfield(app.general_stationGrid, 'numeric');
            app.general_stationLatitude.ValueDisplayFormat = '%.6f';
            app.general_stationLatitude.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_stationLatitude.Tag = 'task_Editable';
            app.general_stationLatitude.Enable = 'off';
            app.general_stationLatitude.Value = -1;
            app.general_stationLongitudeLabel = uilabel(app.general_stationGrid);
            app.general_stationLongitudeLabel.Text = {'Longitude:'; '(graus decimais)'};
            app.general_stationLongitude = uieditfield(app.general_stationGrid, 'numeric');
            app.general_stationLongitude.ValueDisplayFormat = '%.6f';
            app.general_stationLongitude.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_stationLongitude.Tag = 'task_Editable';
            app.general_stationLongitude.Enable = 'off';
            app.general_stationLongitude.Value = -1;
            app.general_lastSessionInfo = uicheckbox(app.general_stationGrid);
            app.general_lastSessionInfo.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.general_lastSessionInfo.Enable = 'off';
            app.general_lastSessionInfo.Text = 'Leitura dados armazenados na última sessão.';
            app.general_versionLabel = uilabel(app.general_Grid);
            app.general_versionLabel.Text = 'WEBSERVICE';
            app.general_versionLock = uiimage(app.general_Grid);
            app.general_versionLock.ImageClickedFcn = createCallbackFcn(app, @general_PanelLockControl, true);
            app.general_versionLock.ImageSource = 'lockClose_32.png';
            app.general_versionPanel = uipanel(app.general_Grid);
            app.server_Grid = uigridlayout(app.general_versionPanel);
            app.server_StatusLabel = uilabel(app.server_Grid);
            app.server_StatusLabel.Text = 'Estado:';
            app.server_Status = uidropdown(app.server_Grid);
            app.server_Status.Items = {'ON', 'OFF'};
            app.server_Status.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_Status.Enable = 'off';
            app.server_Status.Value = 'ON';
            app.server_KeyLabel = uilabel(app.server_Grid);
            app.server_KeyLabel.Text = 'Chave:';
            app.server_Key = uieditfield(app.server_Grid, 'text');
            app.server_Key.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_Key.Enable = 'off';
            app.server_ClientListLabel = uilabel(app.server_Grid);
            app.server_ClientListLabel.Text = {'Lista de clientes:'; '(valores separados por vírgula)'};
            app.server_ClientList = uieditfield(app.server_Grid, 'text');
            app.server_ClientList.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_ClientList.Enable = 'off';
            app.server_IPLabel = uilabel(app.server_Grid);
            app.server_IPLabel.Text = 'Endereço IP (OpenVPN):';
            app.server_IP = uieditfield(app.server_Grid, 'text');
            app.server_IP.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_IP.Enable = 'off';
            app.server_PortLabel = uilabel(app.server_Grid);
            app.server_PortLabel.Text = 'Porta:';
            app.server_Port = uieditfield(app.server_Grid, 'numeric');
            app.server_Port.Limits = [1 65535];
            app.server_Port.RoundFractionalValues = 'on';
            app.server_Port.ValueDisplayFormat = '%d';
            app.server_Port.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.server_Port.Enable = 'off';
            app.server_Port.Value = 1;
            app.general_GraphicsLabel = uilabel(app.general_Grid);
            app.general_GraphicsLabel.Text = 'GRÁFICO';
            app.general_GraphicsPanel = uipanel(app.general_Grid);
            app.general_GraphicsGrid = uigridlayout(app.general_GraphicsPanel);
            app.gpuTypeLabel = uilabel(app.general_GraphicsGrid);
            app.gpuTypeLabel.Text = 'Unidade gráfica:';
            app.gpuType = uidropdown(app.general_GraphicsGrid);
            app.gpuType.Items = {'hardwarebasic', 'hardware', 'software'};
            app.gpuType.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.gpuType.Value = 'hardwarebasic';
            app.openAuxiliarAppAsDocked = uicheckbox(app.general_GraphicsGrid);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na própria janela do appColeta.';
            app.openAuxiliarApp2Debug = uicheckbox(app.general_GraphicsGrid);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @general_ParameterChanged, true);
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.plot_Grid = uigridlayout(app.DocumentGrid);
            app.plot_Title = uilabel(app.plot_Grid);
            app.plot_Title.Text = 'CUSTOMIZAÇÃO DO PLOT';
            app.plot_InteractionsLabel = uilabel(app.plot_Grid);
            app.plot_InteractionsLabel.Text = 'Interações:';
            app.plot_refresh = uiimage(app.plot_Grid);
            app.plot_refresh.ImageClickedFcn = createCallbackFcn(app, @plot_RefreshImageClicked, true);
            app.plot_refresh.ImageSource = 'Refresh_18.png';
            app.plot_InteractionsPanel = uipanel(app.plot_Grid);
            app.plot_InteractionsGrid = uigridlayout(app.plot_InteractionsPanel);
            app.plot_Datatip = uiimage(app.plot_InteractionsGrid);
            app.plot_Datatip.ImageClickedFcn = createCallbackFcn(app, @plot_AxesInteractionsChanged, true);
            app.plot_Datatip.Tooltip = {'Datatip'};
            app.plot_Datatip.ImageSource = 'AxesToolbar_Datatip.png';
            app.plot_DatatipVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_DatatipVisibility.Tag = 'InteractionVisibility';
            app.plot_DatatipVisibility.Tooltip = {'Datatip'};
            app.plot_DatatipVisibility.ImageSource = 'LineH.png';
            app.plot_Pan = uiimage(app.plot_InteractionsGrid);
            app.plot_Pan.ImageClickedFcn = createCallbackFcn(app, @plot_AxesInteractionsChanged, true);
            app.plot_Pan.Tooltip = {'Pan'};
            app.plot_Pan.ImageSource = 'AxesToolbar_Pan.png';
            app.plot_PanVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_PanVisibility.Tag = 'InteractionVisibility';
            app.plot_PanVisibility.Tooltip = {'Datatip'};
            app.plot_PanVisibility.ImageSource = 'LineH.png';
            app.plot_ZoomIn = uiimage(app.plot_InteractionsGrid);
            app.plot_ZoomIn.ImageClickedFcn = createCallbackFcn(app, @plot_AxesInteractionsChanged, true);
            app.plot_ZoomIn.Tooltip = {'Zoom in'};
            app.plot_ZoomIn.ImageSource = 'AxesToolbar_ZoomIn.png';
            app.plot_ZoomInVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_ZoomInVisibility.Tag = 'InteractionVisibility';
            app.plot_ZoomInVisibility.Tooltip = {'Datatip'};
            app.plot_ZoomInVisibility.ImageSource = 'LineH.png';
            app.plot_ZoomOut = uiimage(app.plot_InteractionsGrid);
            app.plot_ZoomOut.ImageClickedFcn = createCallbackFcn(app, @plot_AxesInteractionsChanged, true);
            app.plot_ZoomOut.Tooltip = {'Zoom out'};
            app.plot_ZoomOut.ImageSource = 'AxesToolbar_ZoomOut.png';
            app.plot_ZoomOutVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_ZoomOutVisibility.Tag = 'InteractionVisibility';
            app.plot_ZoomOutVisibility.Tooltip = {'Datatip'};
            app.plot_ZoomOutVisibility.ImageSource = 'LineH.png';
            app.plot_RestoreView = uiimage(app.plot_InteractionsGrid);
            app.plot_RestoreView.ImageClickedFcn = createCallbackFcn(app, @plot_AxesInteractionsChanged, true);
            app.plot_RestoreView.Tooltip = {'Restore view'};
            app.plot_RestoreView.ImageSource = 'AxesToolbar_RestoreView.png';
            app.plot_RestoreViewVisibility = uiimage(app.plot_InteractionsGrid);
            app.plot_RestoreViewVisibility.Tag = 'InteractionVisibility';
            app.plot_RestoreViewVisibility.Tooltip = {'Datatip'};
            app.plot_RestoreViewVisibility.ImageSource = 'LineH.png';
            app.plot_TiledSpacingLabel = uilabel(app.plot_Grid);
            app.plot_TiledSpacingLabel.Text = 'Espaçamento entre eixos:';
            app.plot_TiledSpacing = uidropdown(app.plot_Grid);
            app.plot_TiledSpacing.Items = {'loose', 'compact', 'tight', 'none'};
            app.plot_TiledSpacing.ValueChangedFcn = createCallbackFcn(app, @plot_AxesTiledSpacingChanged, true);
            app.plot_TiledSpacing.Value = 'loose';
            app.plot_colorsLabel = uilabel(app.plot_Grid);
            app.plot_colorsLabel.Text = 'Cores:';
            app.plot_colorsPanel = uipanel(app.plot_Grid);
            app.plot_colorsGrid = uigridlayout(app.plot_colorsPanel);
            app.plot_colorsMinHoldLabel = uilabel(app.plot_colorsGrid);
            app.plot_colorsMinHoldLabel.Text = 'MinHold:';
            app.plot_colorsMinHold = uicolorpicker(app.plot_colorsGrid);
            app.plot_colorsMinHold.ValueChangedFcn = createCallbackFcn(app, @plot_ColorParameterChanged, true);
            app.plot_colorsAverageLabel = uilabel(app.plot_colorsGrid);
            app.plot_colorsAverageLabel.Text = 'Average:';
            app.plot_colorsAverage = uicolorpicker(app.plot_colorsGrid);
            app.plot_colorsAverage.ValueChangedFcn = createCallbackFcn(app, @plot_ColorParameterChanged, true);
            app.plot_colorsMaxHoldLabel = uilabel(app.plot_colorsGrid);
            app.plot_colorsMaxHoldLabel.Text = 'MaxHold:';
            app.plot_colorsMaxHold = uicolorpicker(app.plot_colorsGrid);
            app.plot_colorsMaxHold.ValueChangedFcn = createCallbackFcn(app, @plot_ColorParameterChanged, true);
            app.plot_colorsClearWriteLabel = uilabel(app.plot_colorsGrid);
            app.plot_colorsClearWriteLabel.Text = 'ClearWrite:';
            app.plot_colorsClearWrite = uicolorpicker(app.plot_colorsGrid);
            app.plot_colorsClearWrite.ValueChangedFcn = createCallbackFcn(app, @plot_ColorParameterChanged, true);
            app.plot_WaterfallLabel = uilabel(app.plot_Grid);
            app.plot_WaterfallLabel.Text = 'Waterfall:';
            app.plot_WaterfallPanel = uipanel(app.plot_Grid);
            app.plot_WaterfallGrid = uigridlayout(app.plot_WaterfallPanel);
            app.plot_WaterfallColormapLabel = uilabel(app.plot_WaterfallGrid);
            app.plot_WaterfallColormapLabel.Text = 'Mapa de cor:';
            app.plot_WaterfallColormap = uidropdown(app.plot_WaterfallGrid);
            app.plot_WaterfallColormap.Items = {'gray', 'hot', 'jet', 'summer', 'turbo', 'winter'};
            app.plot_WaterfallColormap.ValueChangedFcn = createCallbackFcn(app, @plot_OthersParameterChanged, true);
            app.plot_WaterfallColormap.Value = 'jet';
            app.plot_WaterfallDepthLabel = uilabel(app.plot_WaterfallGrid);
            app.plot_WaterfallDepthLabel.Text = 'Profundidade:';
            app.plot_WaterfallDepth = uidropdown(app.plot_WaterfallGrid);
            app.plot_WaterfallDepth.Items = {'64', '128', '256', '512'};
            app.plot_WaterfallDepth.ValueChangedFcn = createCallbackFcn(app, @plot_OthersParameterChanged, true);
            app.plot_WaterfallDepth.Value = '128';
            app.plot_IntegrationLabel = uilabel(app.plot_Grid);
            app.plot_IntegrationLabel.Text = 'Integração:';
            app.plot_IntegrationPanel = uipanel(app.plot_Grid);
            app.plot_IntegrationGrid = uigridlayout(app.plot_IntegrationPanel);
            app.plot_IntegrationTraceLabel = uilabel(app.plot_IntegrationGrid);
            app.plot_IntegrationTraceLabel.Text = 'Traço médio:';
            app.plot_IntegrationTrace = uieditfield(app.plot_IntegrationGrid, 'numeric');
            app.plot_IntegrationTrace.Limits = [3 100];
            app.plot_IntegrationTrace.RoundFractionalValues = 'on';
            app.plot_IntegrationTrace.ValueDisplayFormat = '%d';
            app.plot_IntegrationTrace.ValueChangedFcn = createCallbackFcn(app, @plot_OthersParameterChanged, true);
            app.plot_IntegrationTrace.Value = 10;
            app.plot_IntegrationTimeLabel = uilabel(app.plot_IntegrationGrid);
            app.plot_IntegrationTimeLabel.Text = 'Tempo médio escrita:';
            app.plot_IntegrationTime = uieditfield(app.plot_IntegrationGrid, 'numeric');
            app.plot_IntegrationTime.Limits = [3 100];
            app.plot_IntegrationTime.RoundFractionalValues = 'on';
            app.plot_IntegrationTime.ValueDisplayFormat = '%d';
            app.plot_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @plot_OthersParameterChanged, true);
            app.plot_IntegrationTime.Value = 10;
            app.Folders_Grid = uigridlayout(app.DocumentGrid);
            app.config_FolderMapLabel = uilabel(app.Folders_Grid);
            app.config_FolderMapLabel.Text = 'MAPEAMENTO DE PASTAS';
            app.config_FolderMapPanel = uipanel(app.Folders_Grid);
            app.config_FolderMapGrid = uigridlayout(app.config_FolderMapPanel);
            app.config_Folder_userPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_userPathLabel.Text = 'Pasta do usuário:';
            app.config_Folder_userPath = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_userPath.Editable = 'off';
            app.config_Folder_userPathButton = uiimage(app.config_FolderMapGrid);
            app.config_Folder_userPathButton.ImageClickedFcn = createCallbackFcn(app, @config_getFolder, true);
            app.config_Folder_userPathButton.Tag = 'userPath';
            app.config_Folder_userPathButton.ImageSource = 'OpenFile_36x36.png';
            app.config_Folder_tempPathLabel = uilabel(app.config_FolderMapGrid);
            app.config_Folder_tempPathLabel.Text = 'Pasta temporária:';
            app.config_Folder_tempPath = uieditfield(app.config_FolderMapGrid, 'text');
            app.config_Folder_tempPath.Editable = 'off';
            app.ToolbarGrid = uigridlayout(app.GridLayout);
            app.tool_LeftPanelVisibility = uiimage(app.ToolbarGrid);
            app.tool_LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_LeftPanelVisibilityImageClicked, true);
            app.tool_LeftPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end