classdef winServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        DockModule                matlab.ui.container.GridLayout
        DockCloseButton           matlab.ui.control.Image
        DockUndockButton          matlab.ui.control.Image
        ConfirmEditionButtonGrid  matlab.ui.container.GridLayout
        ConfirmEditionButton      matlab.ui.control.Button
        Toolbar                   matlab.ui.container.GridLayout
        StatusLampLabel           matlab.ui.control.Label
        StatusLamp                matlab.ui.control.Lamp
        UITable                   matlab.ui.control.Table
        UITableRefresh            matlab.ui.control.Image
        UITableLabel              matlab.ui.control.Label
        ServerInfo                matlab.ui.control.TextArea
        ServerInfoLabel           matlab.ui.control.Label
        HeaderTitle               matlab.ui.control.Label
        HeaderIcon                matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'SERVER'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        SubTabGroup = struct('Children', -1, 'UserData', [])

        mainApp
        jsBackDoor
        progressDialog
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;

            switch tabIndex
                case 1
                    appName = class(app);
                    elToModify = {
                        app.ConfirmEditionButtonGrid;
                        app.UITableRefresh;
                        app.DockUndockButton;
                        app.DockCloseButton
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.ConfirmEditionButtonGrid.UserData.id, 'style', struct('background', 'none')), ...
                            struct('appName', appName, 'dataTag', app.UITableRefresh.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Atualiza registro de comunicação')), ...
                            struct('appName', appName, 'dataTag', app.DockUndockButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.DockCloseButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')) ...
                        });
                    catch
                    end

                otherwise
                    % ...
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.DockUndockButton.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            updateLayout(app)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateLayout(app)
            if isempty(app.mainApp.tcpServer)
                app.ServerInfo.Value = '';
                app.UITableRefresh.Visible = 0;

                app.StatusLamp.Color = [.64 .08 .18];
                app.StatusLampLabel.Text = 'Servidor não está em execução.';

                app.UITable.Data = table( ...
                    'Size', [0, 8], ...
                    'VariableTypes', {'string', 'string', 'double', 'string', 'string', 'string', 'double', 'string'}, ...
                    'VariableNames', {'Timestamp', 'ClientAddress', 'ClientPort', 'Message', 'ClientName', 'Request', 'NumBytesWritten', 'Status'} ...
                );

                buttonText = 'Iniciar servidor';
                buttonIcon = 'play-16px-white.svg';

            elseif isempty(app.mainApp.tcpServer.Server)
                app.ServerInfo.Value = '';
                app.UITableRefresh.Visible = 0;

                app.StatusLamp.Color = [.5 .5 .5];
                app.StatusLampLabel.Text = sprintf('Servidor ainda não está em execução, apesar do objeto "model.TcpServer" já ter sido criado. Será realizada uma nova tentativa para executá-lo a cada %d segundos.', class.Constants.tcpServerPeriod);

                app.UITable.Data = app.mainApp.tcpServer.Log;

                buttonText = 'Excluir objeto';
                buttonIcon = 'close-16px-white.svg';

            else
                app.ServerInfo.Value = util.HtmlTextGenerator.Server(app.mainApp.tcpServer);
                app.UITableRefresh.Visible = 1;

                app.StatusLamp.Color = [.47 .67 .19];
                app.StatusLampLabel.Text = sprintf('Servidor em execução desde %s.', char(app.mainApp.tcpServer.StartTime));

                app.UITable.Data = app.mainApp.tcpServer.Log;
                
                buttonText = 'Parar servidor';
                buttonIcon = 'debug-stop-16px-white.svg';
            end

            set(app.ConfirmEditionButton, 'Text', buttonText, 'Icon', buttonIcon)
            app.ConfirmEditionButtonGrid.Visible = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)

            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: DockCloseButton, DockUndockButton
        function onDockModuleGroupButtonClicked(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.DockUndockButton
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.DockCloseButton
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Button pushed function: ConfirmEditionButton
        function toolButtonPushed_edit(app, event)
            
            if isempty(app.mainApp.tcpServer)
                app.mainApp.tcpServer = model.TcpServer(app.mainApp);
            
            else
                stop(app.mainApp.tcpServer.Timer)
                delete(app.mainApp.tcpServer.Timer)
                delete(app.mainApp.tcpServer.Server)
                app.mainApp.tcpServer = [];
            end

            updateLayout(app)

        end

        % Image clicked function: UITableRefresh
        function UITableRefreshUITableRefreshClicked(app, event)
            
            updateLayout(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HeaderIcon = uiimage(app.GridLayout);
            app.HeaderIcon.ImageSource = 'cloud-upload - blue.svg';
            app.HeaderTitle = uilabel(app.GridLayout);
            app.HeaderTitle.Interpreter = 'html';
            app.HeaderTitle.Text = {'<b>Servidor de comunicação (TCP)</b>'; '<font style="color: gray; font-size: 11px;">Controle o estado do servidor que atende requisições de clientes externos (Zabbix, por exemplo), compartilhando informações acerca do computador, estação e tarefas de monitoração, e acompanhe o registro de comunicação</font>'};
            app.ServerInfoLabel = uilabel(app.GridLayout);
            app.ServerInfoLabel.Text = 'CARACTERÍSTICAS';
            app.ServerInfo = uitextarea(app.GridLayout);
            app.ServerInfo.Editable = 'off';
            app.UITableLabel = uilabel(app.GridLayout);
            app.UITableLabel.Text = 'COMUNICAÇÃO';
            app.UITableRefresh = uiimage(app.GridLayout);
            app.UITableRefresh.ImageClickedFcn = createCallbackFcn(app, @UITableRefreshUITableRefreshClicked, true);
            app.UITableRefresh.Visible = 'off';
            app.UITableRefresh.ImageSource = 'Refresh_18.png';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'INSTANTE'; 'IP'; 'PORTA'; 'MENSAGEM'; 'CLIENTE'; 'REQUISIÇÃO'; 'BYTES'; 'ESTADO'};
            app.UITable.RowName = {};
            app.Toolbar = uigridlayout(app.GridLayout);
            app.StatusLamp = uilamp(app.Toolbar);
            app.StatusLampLabel = uilabel(app.Toolbar);
            app.StatusLampLabel.Text = 'Desconectado';
            app.ConfirmEditionButtonGrid = uigridlayout(app.GridLayout);
            app.ConfirmEditionButtonGrid.Visible = 'off';
            app.ConfirmEditionButton = uibutton(app.ConfirmEditionButtonGrid, 'push');
            app.ConfirmEditionButton.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.ConfirmEditionButton.Icon = 'play-16px-white.svg';
            app.ConfirmEditionButton.Text = 'Iniciar servidor';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.DockUndockButton = uiimage(app.DockModule);
            app.DockUndockButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockUndockButton.Enable = 'off';
            app.DockUndockButton.ImageSource = 'Undock_18White.png';
            app.DockCloseButton = uiimage(app.DockModule);
            app.DockCloseButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockCloseButton.ImageSource = 'Delete_12SVG_white.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winServer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end