classdef winAppColeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        NavBar                   matlab.ui.container.GridLayout
        AppInfo                  matlab.ui.control.Image
        FigurePosition           matlab.ui.control.Image
        DataHubLamp              matlab.ui.control.Image
        jsBackDoor               matlab.ui.control.HTML
        Tab6Button               matlab.ui.control.StateButton
        Tab5Button               matlab.ui.control.StateButton
        ButtonsSeparator2        matlab.ui.control.Image
        Tab4Button               matlab.ui.control.StateButton
        Tab3Button               matlab.ui.control.StateButton
        Tab2Button               matlab.ui.control.StateButton
        ButtonsSeparator1        matlab.ui.control.Image
        Tab1Button               matlab.ui.control.StateButton
        AppName                  matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        Tab1_Task                matlab.ui.container.Tab
        Tab1Grid                 matlab.ui.container.GridLayout
        Toolbar                  matlab.ui.container.GridLayout
        RevisitTimeInfo          matlab.ui.control.Label
        SelectedTaskInfo         matlab.ui.control.Label
        SelectedTaskSeparator    matlab.ui.control.Image
        TableVisibility          matlab.ui.control.Image
        PanelLeftVisibility      matlab.ui.control.Image
        Document                 matlab.ui.container.GridLayout
        TaskStatusGrid           matlab.ui.container.GridLayout
        GPSLastFixPanel          matlab.ui.container.Panel
        GPSLastFixGrid           matlab.ui.container.GridLayout
        GPSErrorCountIcon        matlab.ui.control.Image
        GPSErrorCount            matlab.ui.control.Label
        GPSLastFix               matlab.ui.control.Label
        GPSLastFixIconGrid       matlab.ui.container.GridLayout
        GPSLastFixIcon           matlab.ui.control.Lamp
        GPSLastFixLabel          matlab.ui.control.Label
        MaskPanel                matlab.ui.container.Panel
        MaskGrid                 matlab.ui.container.GridLayout
        MaskStatus               matlab.ui.control.Label
        MaskLabel                matlab.ui.control.Label
        SweepsPanel              matlab.ui.container.Panel
        SweepsGrid               matlab.ui.container.GridLayout
        ReceiverErrorCountIcon   matlab.ui.control.Image
        ReceiverErrorCount       matlab.ui.control.Label
        RecordingIcon            matlab.ui.control.Image
        Sweeps                   matlab.ui.control.Label
        SweepsLabel              matlab.ui.control.Label
        AxesToolbar              matlab.ui.container.GridLayout
        axesTool_Waterfall       matlab.ui.control.Image
        axesTool_Peak            matlab.ui.control.Image
        axesTool_MaxHold         matlab.ui.control.Image
        axesTool_Average         matlab.ui.control.Image
        axesTool_MinHold         matlab.ui.control.Image
        axesTool_PlotSource      matlab.ui.control.DropDown
        axesTool_ExportGraphics  matlab.ui.control.Image
        axesTool_RestoreView     matlab.ui.control.Image
        AxesContainer            matlab.ui.container.Panel
        MetaData                 matlab.ui.control.Label
        SpectrumFlowList         matlab.ui.control.DropDown
        UITable                  matlab.ui.control.Table
        Tab2_InstrumentList      matlab.ui.container.Tab
        Tab3_TaskEdition         matlab.ui.container.Tab
        Tab4_TaskAdd             matlab.ui.container.Tab
        Tab5_Server              matlab.ui.container.Tab
        Tab6_Config              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'mainApp'
        Context = 'TASK_VIEW'
        AppHandleNameInBase
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        General
        General_I

        rootFolder
        tabGroupController
        renderCount = 0

        executionMode
        progressDialog
        popupContainer
        popupCurrentApp

        SubTabGroup = struct('Children', -1, 'UserData', [])

        % TAREFA+INSTRUMENTOS
        TaskController
        TaskSchedulerTimer

        taskList
        tcpServer
        receiverObj
        gpsObj
        EB500Obj
        EMSatObj
        ERMxObj

        % PLOT
        UIAxes1
        UIAxes2
        RestoreView = struct('Id', {}, 'XLim', {}, 'YLim', {}, 'CLim', {})

        PlotHandles
        PlotStyleDirty (1, 1) logical = false
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        % COMUNICAÇÃO ENTRE PROCESSOS:
        % • ipcMainJSEventsHandler
        %   Eventos recebidos do objeto app.jsBackDoor por meio de chamada 
        %   ao método "sendEventToMATLAB" do objeto "htmlComponent" (no JS).
        %
        % • ipcMainMatlabCallsHandler
        %   Eventos recebidos dos apps secundários.
        %
        % • ipcMainMatlabCallAuxiliarApp
        %   Reencaminha eventos recebidos aos apps secundários, viabilizando
        %   comunicação entre apps secundários e, também, redirecionando os 
        %   eventos JS quando o app secundário é executado em modo DOCK (e, 
        %   por essa razão, usa o "jsBackDoor" do app principal).
        %
        % • ipcMainMatlabOpenPopupApp
        %   Abre um app secundário como popup, no mainApp.
        %-----------------------------------------------------------------%
        function ipcMainJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    % MATLAB-JS BRIDGE (matlabJSBridge.js)
                    case 'renderer'
                        MFilePath   = fileparts(mfilename('fullpath'));
                        parpoolFlag = false;

                        if ~app.renderCount
                            appEngine.activate(app, app.Role, MFilePath, parpoolFlag)
                        else
                            currentTaskIdx = app.UITable.Selection;
                            if ~isempty(currentTaskIdx)
                                app.UITable.Selection = [];
                                onTaskSelectionChanged(app, struct('PreviousSelection', [], 'Selection', []))
                            end

                            appEngine.beforeReload(app, app.Role)
                            appEngine.activate(app, app.Role, MFilePath, parpoolFlag)

                            if ~isempty(currentTaskIdx)
                                app.UITable.Selection = currentTaskIdx;
                                onTaskSelectionChanged(app, struct('PreviousSelection', [], 'Selection', currentTaskIdx))
                            end
                        end
                        
                        app.renderCount = app.renderCount+1;

                    case 'unload'
                        closeFcn(app)

                    case 'closeFcnCallFromPopupApp'
                        context = event.HTMLEventData.context;
                        popupCurrentAppTag = event.HTMLEventData.dockAppName;

                        switch context
                            case {'mainApp', app.Context}
                                hApp = app;
                            otherwise
                                hApp = getAppHandle(app.tabGroupController, context);
                        end
                        
                        if ~isempty(hApp) && isvalid(hApp)
                            deleteContextMenu(app.tabGroupController, hApp.UIFigure, popupCurrentAppTag)
                        end

                        if contains(class(app.popupCurrentApp), popupCurrentAppTag)
                            delete(app.popupCurrentApp)
                            app.popupCurrentApp = [];
                        end
                    
                    case 'customForm'
                        switch event.HTMLEventData.uuid
                            case 'openDevTools'
                                if isequal(app.General.operationMode.DevTools, rmfield(event.HTMLEventData, 'uuid'))
                                    webWin = struct(struct(struct(app.UIFigure).Controller).PlatformHost).CEF;
                                    webWin.openDevTools();
                                end

                            otherwise
                                error('UnexpectedEvent')
                        end

                    case 'getNavigatorBasicInformation'
                        app.General.AppVersion.browser = event.HTMLEventData;

                    case 'findResourceStaticURL'
                        resourceStaticURL = event.HTMLEventData;
                        if ~isempty(resourceStaticURL)
                            app.General.AppVersion.application.resourceStaticURL = resourceStaticURL;
                        end

                    case {'onStartTaskRequest', 'onStopTaskRequest', 'onEditTaskRequested', 'onDeleteTaskRequest', 'onViewLogRequest'}
                        % event.HTMLEventData é o índice da tarefa (linha da tabela).
                        taskIdx = event.HTMLEventData;

                        if taskIdx < 1 || taskIdx > numel(app.TaskController.Tasks)
                            return
                        end

                        switch event.HTMLEventName
                            case 'onStartTaskRequest'
                                startOrReplayTask(app, taskIdx)
                            case 'onStopTaskRequest'
                                stopTask(app, taskIdx)
                            case 'onEditTaskRequested'
                                editTask(app, taskIdx)
                            case 'onDeleteTaskRequest'
                                deleteTask(app, taskIdx)
                            case 'onViewLogRequest'
                                showTaskLog(app, taskIdx)
                        end

                    case 'auxApp.winAddTask.AntennaTree'
                        ipcMainMatlabCallAuxiliarApp(app, 'TASK_ADD', 'MATLAB', 'deleteAddedAntenna')

                    otherwise
                        error('winAppColeta:UnexpectedEvent', 'Unexpected event "%s"', event.HTMLEventName)
                end
                drawnow

            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME));
            end
        end

        %-----------------------------------------------------------------%
        function varargout = ipcMainMatlabCallsHandler(app, callingApp, eventName, varargin)
            varargout = {};

            try
                switch eventName
                    case 'closeFcn'
                        auxAppTag = varargin{1};
                        closeModule(app.tabGroupController, auxAppTag, app.General)

                    case 'dockButtonPushed'
                        auxAppTag = varargin{1};
                        varargout{1} = {app};

                        if strcmp(auxAppTag, 'TASK_ADD')
                            [auxAppIsOpen, auxAppHandle] = checkStatusModule(app.tabGroupController, auxAppTag);

                            if auxAppIsOpen
                                varargout{1} = {app, auxAppHandle.infoEdition};
                            end
                        end

                    case 'onUpdateLastVisitedFolder'
                        filePath = varargin{1};
                        updateLastVisitedFolder(app, filePath)

                    otherwise
                        switch class(callingApp)
                            % auxApp.winConfig (CONFIG)
                            case {'auxApp.winConfig', 'auxApp.winConfig_exported'}
                                switch eventName
                                    case 'checkDataHubLampStatus'
                                        updateWarningLampVisibility(app)

                                    case 'openDevTools'
                                        dialogBox    = struct('id', 'login',    'label', 'Usuário: ', 'type', 'text');
                                        dialogBox(2) = struct('id', 'password', 'label', 'Senha: ',   'type', 'password');
                                        sendEventToHTMLSource(app.jsBackDoor, 'customForm', struct('UUID', 'openDevTools', 'Fields', dialogBox))
        
                                    case 'onAxesTileSpacingChange'
                                        tileSpacing = varargin{1};
                                        app.UIAxes1.Parent.TileSpacing = tileSpacing;
                
                                    case 'onPlotColorChange'
                                        plotTag = varargin{1};
                                        if ~isempty(eval(sprintf('app.PlotHandles.%s', plotTag)))
                                            app.PlotStyleDirty = true;
                                        end
                
                                    case 'onWaterfallColormapChange'
                                        waterfallColormap = varargin{1};
                                        colormap(app.UIAxes2, waterfallColormap)
        
                                    otherwise
                                        error('winAppColeta:UnexpectedCall', 'Unexpected call "%s"', eventName)
                                end

                            % auxApp.winTaskList (TASK_EDIT)
                            case {'auxApp.winTaskList', 'auxApp.winTaskList_exported'}
                                switch eventName
                                    case 'onTaskListEdit'
                                        app.taskList = util.TaskScriptIO.loadScriptList(app.rootFolder, 'winAppColetaV2');
        
                                    otherwise
                                        error('winAppColeta:UnexpectedCall', 'Unexpected call "%s"', eventName)
                                end

                            % auxApp.winAddTask (TASK_ADD)
                            case {'auxApp.winAddTask', 'auxApp.winAddTask_exported'}
                                switch eventName
                                    case 'onTaskAddingOrEditing'
                                        auxAppTag   = varargin{1};
                                        infoEdition = varargin{2};
                                        newTask     = varargin{3};
                
                                        closeModule(app.tabGroupController, auxAppTag, app.General)

                                        if contains(newTask.Type, 'Drive-test (Level+Azimuth)') && (strcmp(app.executionMode, 'webApp') || ~class.EB500Lib.isGUIRunning())
                                            ui.Dialog(app.UIFigure, 'warning', [ ...
                                                'A tarefa "Drive-test (Level+Azimuth)" usa comandos não ' ...
                                                'documentados do receptor R&S EB500, sendo operacional ' ...
                                                'apenas quando a aplicação EB500GUI está aberta. Não foi possível ' ...
                                                'confirmar, entre os processos em execução neste computador, ' ...
                                                'que esta aplicação está aberta, operação que deve ser realizada ' ...
                                                'pelo usuário previamente ao início da execução da tarefa.' ...
                                            ]);
                                        end
                
                                        % O try/catch possibilita a inclusão do progressDialog sem que 
                                        % exista o risco dele ficar visível, caso ocorra algum erro não
                                        % mapeado no método da classe.
                                        try
                                            app.progressDialog.Visible = 'visible';
                                            [app.TaskController.Tasks, msgError]    = AddOrEditTask(app.TaskController.Tasks, infoEdition, newTask, app.EMSatObj, app.ERMxObj);
                                            app.progressDialog.Visible = 'hidden';
                            
                                            if isempty(msgError)
                                                taskSchedulerTimerFcn(app)                                 % Startup of every task
                                            else
                                                ui.Dialog(app.UIFigure, 'warning', msgError);
                                            end
                                        catch
                                        end

                                    otherwise
                                        error('winAppColeta:UnexpectedCall', 'Unexpected call "%s"', eventName)
                                end

                            otherwise
                                error('winAppColeta:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                        end
                end                

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);            
            end
        end

        %-----------------------------------------------------------------%
        function ipcMainMatlabCallAuxiliarApp(app, auxAppName, communicationType, varargin)
            hAuxApp = getAppHandle(app.tabGroupController, auxAppName);

            if ~isempty(hAuxApp)
                switch communicationType
                    case 'MATLAB'
                        operationType = varargin{1};
                        ipcSecondaryMatlabCallsHandler(hAuxApp, app, operationType, varargin{2:end});
                    case 'JS'
                        event = varargin{1};
                        ipcSecondaryJSEventsHandler(hAuxApp, event)
                end
            end
        end

        %-----------------------------------------------------------------%
        function ipcMainMatlabOpenPopupApp(app, callingApp, auxAppName, context, varargin)
            arguments
                app
                callingApp
                auxAppName char {mustBeMember(auxAppName, {'Tracking'})}
                context    char {mustBeMember(context, {'mainApp', 'TASK_VIEW', 'TASK_EDIT', 'TASK_ADD', 'INSTRUMENT', 'SERVER', 'CONFIG'})}
            end

            arguments (Repeating)
                varargin 
            end

            % Um popup ativo do mesmo tipo é reaproveitado, pois só um app 
            % dock é suportado por vez.
            hasOpenPopup = ~isempty(app.popupCurrentApp) && isvalid(app.popupCurrentApp);
            if hasOpenPopup
                isSamePopupType = contains(class(app.popupCurrentApp), ['dock' auxAppName]);
                isUpdatable     = isprop(app.popupCurrentApp, 'isUpdatable') && app.popupCurrentApp.isUpdatable;

                if isSamePopupType && isUpdatable
                    updateState(app.popupCurrentApp, varargin{:})
                    return
                end
            end

            requestVisibilityChange(callingApp.progressDialog, 'visible', 'unlocked')
            inputArguments = [{app, callingApp, context}, varargin];

            if app.General.operationMode.Debug
                app.popupCurrentApp = eval(sprintf('auxApp.dock%s(inputArguments{:})', auxAppName));
                app.popupCurrentApp.isDocked = false;

            else
                popupSpecifications = table( ...
                    'Size', [15, 4], ...
                    'VariableTypes', {'string', 'double', 'double', 'logical'}, ...
                    'VariableNames', {'AuxAppName', 'Width', 'Height', 'IsFluid'} ...
                );
                popupSpecifications(1, :) = {"Tracking", 622, 302, false};

                auxAppNameIdx = find(popupSpecifications.AuxAppName == string(auxAppName), 1);
                screenWidth = popupSpecifications.Width(auxAppNameIdx);
                screenHeight = popupSpecifications.Height(auxAppNameIdx);
                isFluid = popupSpecifications.IsFluid(auxAppNameIdx);

                ui.PopUpContainer(callingApp, screenWidth, screenHeight)
                auxDockAppName = sprintf('auxApp.dock%s', auxAppName);
                app.popupCurrentApp = feval([auxDockAppName '_exported'], callingApp.popupContainer, inputArguments{:});
                
                ui.CustomizationBase.getElementsDataTag({
                    callingApp.popupContainer;
                    app.popupCurrentApp.GridLayout
                });

                if isFluid
                    sizing = struct('type', 'fluid', 'width', 90, 'height', 80);
                else
                    sizing = struct('type', 'fixed', 'width', screenWidth, 'height', screenHeight+31);
                end

                sendEventToHTMLSource(callingApp.jsBackDoor, 'dockContainer', struct( ...
                    'dockAppName', auxDockAppName, ...
                    'dockAppDataTag', app.popupCurrentApp.GridLayout.UserData.id, ...
                    'dockAppContainerDataTag', callingApp.popupContainer.UserData.id, ...
                    'sizing', sizing, ...
                    'context', context, ...
                    'numCanvasElements', numel(findobj(app.popupCurrentApp.Container, 'Type', 'axes')) ...
                ))

                app.popupCurrentApp.GridLayout.UserData.auxDockAppName = auxDockAppName;
                callingApp.popupContainer.UserData.auxDockAppName = auxDockAppName;
            end

            requestVisibilityChange(callingApp.progressDialog, 'hidden', 'unlocked')
        end
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function navigateToTab(app, clickedButton)
            onTabNavigatorButtonPushed(app, struct('Source', clickedButton, 'PreviousValue', false))
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;

            switch tabIndex
                case 1
                    appName = class(app);
                    elToModify = {
                        app.Tab1Button;
                        app.Tab2Button;
                        app.Tab3Button;
                        app.Tab4Button;
                        app.Tab5Button;
                        app.Tab6Button;
                        ...
                        app.SpectrumFlowList;
                        app.MetaData;
                        app.AxesToolbar;
                        ...
                        app.axesTool_RestoreView;
                        app.axesTool_ExportGraphics;
                        app.axesTool_PlotSource;
                        app.axesTool_MinHold;
                        app.axesTool_Average;
                        app.axesTool_MaxHold;
                        app.axesTool_Peak;
                        app.axesTool_Waterfall;
                        ...
                        app.PanelLeftVisibility;
                        app.TableVisibility
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        ui.TextView.startup(app.jsBackDoor, app.MetaData, appName);
                    catch
                    end

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.AxesToolbar.UserData.id, 'styleImportant', struct('borderTopLeftRadius', '0', 'borderTopRightRadius', '0')), ...
                            struct('appName', appName, 'dataTag', app.SpectrumFlowList.UserData.id, 'selector', 'input', 'styleImportant', struct('height', '44px'), 'dropDownBackgroundColor', struct('items', 'rgba(183, 49, 44, 0.75)', 'selectedItem', 'rgb(108, 4, 4)')), ...
                            ...
                            struct('appName', appName, 'dataTag', app.axesTool_RestoreView.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Restaura limites iniciais dos eixos')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_ExportGraphics.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exporta plot')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_PlotSource.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Seleciona tipo de plot')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_MinHold.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe curva "MinHold"')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_Average.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe curva "Average"')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_MaxHold.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe curva "MaxHold"')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_Peak.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Excursão de pico')), ...
                            struct('appName', appName, 'dataTag', app.axesTool_Waterfall.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Exibe waterfall')), ...
                            ...
                            struct('appName', appName, 'dataTag', app.PanelLeftVisibility.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Visibilidade do painel à esquerda')), ...
                            struct('appName', appName, 'dataTag', app.TableVisibility.UserData.id, 'tooltip', struct('defaultPosition', 'top', 'textContent', 'Visibilidade da tabela')) ...
                        });
                    catch
                    end

                otherwise
                    % ...
            end
        end

        %-----------------------------------------------------------------%
        function loadConfigurationFile(app, appName, MFilePath)
            % "GeneralSettings.json"
            [app.General_I, msgWarning] = appEngine.util.generalSettingsLoad(appName, app.rootFolder);
            if ~isempty(msgWarning)
                ui.Dialog(app.UIFigure, 'error', msgWarning);
            end

            % Para criação de arquivos temporários, cria-se uma pasta da 
            % sessão.
            tempDir = tempname;
            mkdir(tempDir)
            app.General_I.fileFolder.tempPath  = tempDir;
            app.General_I.fileFolder.MFilePath = MFilePath;
            app.General_I.context.CONFIG.station.computer = appEngine.util.OperationSystem("computerName");

            switch app.executionMode
                case 'webApp'
                    % Força a exclusão do SplashScreen do MATLAB Web Server.
                    sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");

                    app.General_I.operationMode.Debug = false;
                    app.General_I.operationMode.Dock  = true;
                    
                    % A pasta do usuário não é configurável, mas obtida por 
                    % meio de chamada a uiputfile. 
                    app.General_I.fileFolder.userPath = tempDir;

                otherwise    
                    % Resgata a pasta de trabalho do usuário (configurável).
                    userPaths = appEngine.util.UserPaths(app.General_I.fileFolder.userPath);
                    app.General_I.fileFolder.userPath = userPaths{end};

                    switch app.executionMode
                        case 'desktopStandaloneApp'
                            app.General_I.operationMode.Debug = false;
                        case 'MATLABEnvironment'
                            app.General_I.operationMode.Debug = true;
                    end
            end

            app.General = app.General_I;
            app.General.AppVersion = util.getAppVersion(app.rootFolder, MFilePath, tempDir);
            sendEventToHTMLSource(app.jsBackDoor, 'getNavigatorBasicInformation')

            % Ideia é identificar URL de pasta estática servida pelo backend, de 
            % forma que possam ser inseridas imagens em uilabel (como ui.TextView).
            try
                [~, resourceName, resourceExt] = fileparts(app.PanelLeftVisibility.ImageSource);
                sendEventToHTMLSource(app.jsBackDoor, 'findResourceStaticURL', struct('resourceName', [resourceName resourceExt], 'resourceTag', 'img', 'resourceId', app.PanelLeftVisibility.UserData.id))
            catch
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % Um dos arquivos que compõem a subpasta "config", copiada para
            % "ProgramData/ANATEL/appColeta" na primeira execução, é o arquivo 
            % "TaskList.json".
            app.taskList = util.TaskScriptIO.loadScriptList(app.rootFolder, 'winAppColetaV2');

            % Others...
            resourcesFolder = fullfile(app.General.fileFolder.MFilePath, 'resources');
            app.receiverObj = model.Receiver(app.rootFolder, resourcesFolder);
            app.gpsObj = model.GPS(app.rootFolder, resourcesFolder);            
            app.EB500Obj = class.EB500Lib(resourcesFolder);
            app.EMSatObj = class.EMSatLib(app.rootFolder);
            app.ERMxObj = class.ERMxLib(resourcesFolder);            

            app.TaskController = model.TaskController(app);
            registerTaskControllerListeners(app)

            if app.General.context.SERVER.status
                try
                    app.tcpServer = model.TcpServer(app);
                catch
                    app.tcpServer = [];
                end
            end

            resetRestoreView(app)
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            app.tabGroupController = ui.TabNavigator(app.NavBar, app.TabGroup, app.progressDialog, app.jsBackDoor);
            addComponent(app.tabGroupController, "Built-in", "",                     app.Tab1Button, "AlwaysOn", struct('On', '', 'Off', ''), matlab.graphics.GraphicsPlaceholder, 1)
            addComponent(app.tabGroupController, "External", "auxApp.winInstrument", app.Tab2Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      2)
            addComponent(app.tabGroupController, "External", "auxApp.winTaskList",   app.Tab3Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      3)
            addComponent(app.tabGroupController, "External", "auxApp.winAddTask",    app.Tab4Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      4)
            addComponent(app.tabGroupController, "External", "auxApp.winServer",     app.Tab5Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      5)
            addComponent(app.tabGroupController, "External", "auxApp.winConfig",     app.Tab6Button, "AlwaysOn", struct('On', '', 'Off', ''), app.Tab1Button,                      6)
            app.tabGroupController.inlineSVG = true;

            app.axesTool_MinHold.UserData    = struct('id', '', 'status', false, 'icon', struct('On', 'MinHold_32Filled.png', 'Off', 'MinHold_32.png'));
            app.axesTool_Average.UserData    = struct('id', '', 'status', false, 'icon', struct('On', 'Average_32Filled.png', 'Off', 'Average_32.png'));
            app.axesTool_MaxHold.UserData    = struct('id', '', 'status', false, 'icon', struct('On', 'MaxHold_32Filled.png', 'Off', 'MaxHold_32.png'));
            app.axesTool_Peak.UserData       = struct('id', '', 'status', false);
            app.axesTool_Waterfall.UserData  = struct('id', '', 'status', false);

            app.PanelLeftVisibility.UserData.status = true;
            app.TableVisibility.UserData.status = true;
            
            initializeAxes(app)

            % Customiza uitable, atualizando-se em seguida.
            addStyle(app.UITable, uistyle("Interpreter", "html"));
            addStyle(app.UITable, uistyle("HorizontalAlignment", "center"), "column", 7);
            app.UITable.RowName = 'numbered';
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            updateWarningLampVisibility(app)
            createTaskSchedulerTimer(app)

            if app.General.context.CONFIG.restoreLastSession
                restoreTasksFromFile(app)
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function refreshTaskTable(app, selectedTaskIdx)
            arguments
                app
                selectedTaskIdx = []
            end

            taskTable = table( ...
                'Size', [0, 7], ...
                'VariableTypes', {'string', 'string', 'string', 'string', 'string', 'string', 'cell'}, ...
                'VariableNames', {'Name', 'Receiver', 'Created', 'BeginTime', 'EndTime', 'Status', 'Operation'} ...
            );

            hasTask = ~isempty(app.TaskController.Tasks);

            if hasTask
                % Verifica se o handle para o app continua ativo no workspace
                % base do MATLAB, possibilitando que clicks na uitable sejam 
                % capturados corretamente.
                appHandleNameInBase = app.AppHandleNameInBase;
                if isempty(appHandleNameInBase) || ~evalin('base', sprintf('exist("%s", "var") && isa(%s, "%s") && isvalid(%s)', appHandleNameInBase, appHandleNameInBase, class(app), appHandleNameInBase))
                    app.AppHandleNameInBase = ui.Table.exportAppHandleToBaseWorkspace(app);
                end
                
                for taskIdx = 1:numel(app.TaskController.Tasks)
                    endedAt = '-';
                    if ~isnat(app.TaskController.Tasks(taskIdx).Timing.endedAt) && ~isinf(app.TaskController.Tasks(taskIdx).Timing.endedAt)
                        endedAt = datestr(app.TaskController.Tasks(taskIdx).Timing.endedAt, 'dd/mm/yyyy HH:MM:SS');
                    end
    
                    operation = {
                        sprintf('<a href="matlab:evalin(''base'', ''ipcMainJSEventsHandler(%s, struct(''''HTMLEventName'''', ''''onStartTaskRequest'''',  ''''HTMLEventData'''', %d))'')">▶️</a>', app.AppHandleNameInBase, taskIdx);
                        sprintf('<a href="matlab:evalin(''base'', ''ipcMainJSEventsHandler(%s, struct(''''HTMLEventName'''', ''''onStopTaskRequest'''',   ''''HTMLEventData'''', %d))'')">⬛</a>', app.AppHandleNameInBase, taskIdx);
                        sprintf('<a href="matlab:evalin(''base'', ''ipcMainJSEventsHandler(%s, struct(''''HTMLEventName'''', ''''onEditTaskRequested'''', ''''HTMLEventData'''', %d))'')">✏️</a>', app.AppHandleNameInBase, taskIdx);
                        sprintf('<a href="matlab:evalin(''base'', ''ipcMainJSEventsHandler(%s, struct(''''HTMLEventName'''', ''''onDeleteTaskRequest'''', ''''HTMLEventData'''', %d))'')">❌</a>', app.AppHandleNameInBase, taskIdx);
                        sprintf('<a href="matlab:evalin(''base'', ''ipcMainJSEventsHandler(%s, struct(''''HTMLEventName'''', ''''onViewLogRequest'''',    ''''HTMLEventData'''', %d))'')">📋</a>', app.AppHandleNameInBase, taskIdx)
                    };
    
                    % Índices: 1 iniciar, 2 interromper, 3 editar, 4 excluir, 5 log.
                    taskStatus   = app.TaskController.Tasks(taskIdx).Status;
                    removedItems = 2;
                    if strcmp(taskStatus, 'Em andamento')
                        removedItems = 1;
                    end
    
                    if ismember(taskStatus, {'Na fila', 'Em andamento'})
                        removedItems(end+1) = 3;
                    end
                    operation(removedItems) = [];
            
                    taskTable(end+1,:) = { ...
                        app.TaskController.Tasks(taskIdx).TaskSpec.Script.Name, ...
                        app.TaskController.Tasks(taskIdx).ReceiverId, ...
                        app.TaskController.Tasks(taskIdx).Timing.createdAt, ...
                        datestr(app.TaskController.Tasks(taskIdx).Timing.startedAt, 'dd/mm/yyyy HH:MM:SS'), ...
                        endedAt, ...
                        app.TaskController.Tasks(taskIdx).Status, ...
                        strjoin(operation, '&emsp;') ...
                    };
                end

                if isempty(selectedTaskIdx) || selectedTaskIdx > height(taskTable)
                    selectedTaskIdx = 1;
                end
            end

            set(app.UITable, 'Data', taskTable, 'Selection', selectedTaskIdx)
            updateErrorCount(app, app.UITable.Selection)
        
            selectedBandIdx = 1;
            loadSelectedTask(app, selectedBandIdx)
            
            drawnow
        end

        %-----------------------------------------------------------------%
        function loadSelectedTask(app, selectedBandIdx)
            taskTable = app.UITable.Data;
            taskIdx = app.UITable.Selection;

            cellStyleIdxs = find(app.UITable.StyleConfigurations.Target == "cell");
            if ~isempty(cellStyleIdxs)
                removeStyle(app.UITable, cellStyleIdxs)
            end

            if ~isempty(taskTable) && ~isempty(taskIdx)
                addStyle(app.UITable, uistyle('Icon', 'eye.svg', 'IconAlignment', 'rightmargin'), 'cell', [taskIdx, 1])

                numBands = numel(app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band);
                items = {};

                for bandIdx = 1:numBands
                    if bandIdx < 10
                        charSpace = '&emsp;&emsp;&ensp;';
                    else
                        charSpace = '&emsp;&emsp;&emsp;';
                    end


                    antenna = app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).instrAntenna;
                    if ~isempty(antenna)
                        antenna = sprintf('(%s)', antenna);
                    end
                    
                    items{end+1} = sprintf('ID %d: %.3f – %.3f MHz %s<br>%s%d pts&nbsp;&nbsp;•&nbsp;&nbsp;%s&nbsp;&nbsp;•&nbsp;&nbsp;%s', ...
                         app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).ID, ...
                         app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).FreqStart / 1e+6, ...
                         app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).FreqStop  / 1e+6, ...
                         antenna, ...
                         charSpace, ...
                         app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).instrDataPoints, ...
                         app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).instrResolution, ...
                         app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).instrDetector ...
                     );
                end
                
                if isempty(app.SpectrumFlowList.StyleConfigurations)
                    addStyle(app.SpectrumFlowList, uistyle('Interpreter', 'html'))
                end

                set(app.SpectrumFlowList, 'Items', items, 'ItemsData', 1:numBands, 'Value', selectedBandIdx)

            else
                removeStyle(app.SpectrumFlowList)
                app.SpectrumFlowList.Items = {};
            end

            onBandSelectionChanged(app)
            drawnow
        end

        %-----------------------------------------------------------------%
        function loadSelectedBand(app, taskIdx, bandIdx)
            taskTable = app.UITable.Data;
            hasSelection = ~isempty(taskTable) && ~isempty(taskIdx);
            hasBands = false;

            if hasSelection
                hasBands = ~isempty(app.TaskController.Tasks(taskIdx).Bands);
            end

            resetPlotState(app)
            updatePlotSourceOptions(app, hasSelection, hasBands, taskIdx, bandIdx);

            updateTaskMetaData(app)
            updateMaskStatus(app, true, taskIdx, bandIdx)

            if hasSelection && hasBands
                if ~isempty(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall)
                    waterfallIdx = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall.idx;

                    if waterfallIdx
                        updatePlot(app, taskIdx, bandIdx)
                    end
                end

                if ~isempty(app.TaskController.Tasks(taskIdx).Bands(bandIdx).OutputFile)
                    recordedSweeps = app.TaskController.Tasks(taskIdx).Bands(bandIdx).OutputFile.WritedSamples;
                else
                    recordedSweeps = -1;
                end
                app.Sweeps.Text = string(recordedSweeps);

                if ~contains(app.TaskController.Tasks(taskIdx).TaskSpec.Type, 'PRÉVIA') && strcmp(app.TaskController.Tasks(taskIdx).Status, 'Em andamento') && app.TaskController.Tasks(taskIdx).Bands(bandIdx).IsActive
                    app.RecordingIcon.Visible = 'on';
                else
                    app.RecordingIcon.Visible = 'off';
                end

                updateGPSStatus(app, app.TaskController.Tasks(taskIdx).GPSLastFix)

                app.SelectedTaskInfo.Text = sprintf('Tarefa "<b>%s</b>"\nFaixa %d de %d: %.3f – %.3f MHz', app.TaskController.Tasks(taskIdx).TaskSpec.Script.Name, bandIdx, numel(app.TaskController.Tasks(taskIdx).Bands), app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).FreqStart / 1e+6, app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).FreqStop  / 1e+6);
                updateRevisitTimeInfo(app, taskIdx, bandIdx)

            else
                app.Sweeps.Text = string(-1);
                app.RecordingIcon.Visible = 'off';
                updateGPSStatus(app, [])
                
                app.SelectedTaskInfo.Text = '';
                updateRevisitTimeInfo(app, [], [])
            end

            app.SelectedTaskSeparator.Visible = ~isempty(app.SelectedTaskInfo.Text);
        end

        %-----------------------------------------------------------------%
        function updateTaskMetaData(app)
            taskTable = app.UITable.Data;
            taskIdx = app.UITable.Selection;
            bandIdx = app.SpectrumFlowList.Value;

            hasSelection = ~isempty(taskTable) && ~isempty(taskIdx);
            hasBands = false;

            if hasSelection
                hasBands = ~isempty(app.TaskController.Tasks(taskIdx).Bands);
            end

            if hasSelection && hasBands
                app.MetaData.Text = util.HtmlTextGenerator.Task(app.TaskController.Tasks, app.TaskController.RevisitInfo, taskIdx, bandIdx);
            else
                app.MetaData.Text = '';
            end
        end

        %-----------------------------------------------------------------%
        function updateErrorCount(app, taskIdx)
            if ~isempty(taskIdx) && app.TaskController.Tasks(taskIdx).RetryPolicy.receiver.failureCount
                set(app.ReceiverErrorCount, 'Text', string(app.TaskController.Tasks(taskIdx).RetryPolicy.receiver.failureCount), 'Visible', 'on')
                app.ReceiverErrorCountIcon.Visible = 'on';
            else
                set(app.ReceiverErrorCount, 'Text', '0', 'Visible', 'off')
                app.ReceiverErrorCountIcon.Visible = 'off';
            end
        end

        %-----------------------------------------------------------------%
        function updateRevisitTimeInfo(app, taskIdx, bandIdx)
            app.RevisitTimeInfo.Text = '';

            if isempty(taskIdx) || isempty(bandIdx)
                return
            end

            band = app.TaskController.Tasks(taskIdx).Bands(bandIdx);
            if band.NumSweeps > 0
                app.RevisitTimeInfo.Text = sprintf('%d varreduras\n%.3f seg', band.NumSweeps, band.RevisitTime);
            end
        end

        %-----------------------------------------------------------------%
        function updateGPSStatus(app, gpsData)
            if isempty(gpsData)
                app.GPSLastFix.Text = {'<b style="color: #a2142f; font-size: 14;">-1.000</b> LAT '; '<b style="color: #a2142f; font-size: 14;">-1.000</b> LON '; 'dd-mmm-yyyy '; 'HH:MM:SS '};
                app.GPSLastFixIcon.Color = [0.50,0.50,0.50];
                return
            end

            switch gpsData.Status
                case  1
                    iconColor = [0.47,0.67,0.19];
                case  0
                    iconColor = [0.64,0.08,0.18];
                case -1
                    iconColor = [0.50,0.50,0.50];
            end
        
            app.GPSLastFix.Text = sprintf([ ...
                '<b style="color: #a2142f; font-size: 14;">%.3f</b> LAT \n' ...
                '<b style="color: #a2142f; font-size: 14;">%.3f</b> LON \n' ...
                '%s \n%s ' ...
            ], gpsData.Latitude, gpsData.Longitude, extractBefore(gpsData.TimeStamp, ' '), extractAfter(gpsData.TimeStamp, ' '));
            app.GPSLastFixIcon.Color = iconColor;
        end

        %-----------------------------------------------------------------%
        function updateMaskStatus(app, maskTrigger, taskIdx, bandIdx)
            taskTable = app.UITable.Data;
            hasSelection = ~isempty(taskTable) && ~isempty(taskIdx);
            hasMask = false;

            if hasSelection
                hasBands = ~isempty(app.TaskController.Tasks(taskIdx).Bands);
                if hasBands
                    hasMask = ~isempty(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask);
                end
            end

            if ~hasMask
                app.MaskStatus.Enable = 0;
                app.MaskStatus.Text = {
                    '<b style="color: #a2142f; font-size: 14;">-1</b> ';
                    'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> ';
                    'ROMPIMENTOS '; '<font style="color: #a2142f;">-1.000 MHz ';
                    '⌂ -1.0 kHz ';
                    'Ʌ -1.0 dB </font>';
                    'dd-mmm-yyyy ';
                    'HH:MM:SS '
                };
                return
            end

            app.MaskStatus.Enable = 1;
            validations = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ValidationCount;
            brokenCount = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ViolationCount;

            if maskTrigger
                if ~isempty(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ExceedingPeaks)
                    numPeaks = sprintf(' (%d)', height(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ExceedingPeaks));
                    freqCenter = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ExceedingPeaks.centerFrequencyMHz(1);
                    bandWidth = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ExceedingPeaks.bandwidthKHz(1);
                    prominence = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ExceedingPeaks.prominence(1);
                    dayTimeStamp = extractBefore(char(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.LastViolationAt), ' ');
                    hourTimeStamp = extractAfter(char(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.LastViolationAt), ' ');
                else
                    numPeaks = '';
                    freqCenter = -1;
                    bandWidth = -1;
                    prominence = -1;
                    dayTimeStamp = 'dd-mmm-yyyy';
                    hourTimeStamp = 'HH:MM:SS';
                end
        
                app.MaskStatus.Text = sprintf([ ...
                    '<b style="color: #a2142f; font-size: 14;">%.0f</b> \nVALIDAÇÕES \n' ...
                    '<b style="color: #a2142f; font-size: 14;">%.0f%s</b> \nROMPIMENTOS \n' ...
                    '<font style="color: #a2142f;">%.3f MHz \n⌂ %.1f kHz \nɅ %.1f dB</font> \n%s \n%s ' ...
                ], validations, brokenCount, numPeaks, freqCenter, bandWidth, prominence, dayTimeStamp, hourTimeStamp);

            else
                app.MaskStatus.Text = replace(app.MaskStatus.Text, [extractBefore(app.MaskStatus.Text, 'VALIDAÇÕES') 'VALIDAÇÕES'], sprintf('<b style="color: #a2142f; font-size: 14;">%.0f</b> \nVALIDAÇÕES', validations));
            end
        end


        %-----------------------------------------------------------------%
        % ## EXECUÇÃO DAS TAREFAS ##
        %-----------------------------------------------------------------%
        function startOrReplayTask(app, taskIdx)
            switch app.TaskController.Tasks(taskIdx).Status
                case {'Cancelada', 'Erro', 'Concluída'}
                    timestamp = datetime('now');

                    switch app.TaskController.Tasks(taskIdx).TaskSpec.Script.Observation.Type
                        case 'Duration'
                            app.TaskController.Tasks(taskIdx).Timing.startedAt = timestamp;
                            app.TaskController.Tasks(taskIdx).Timing.endedAt   = timestamp + seconds(app.TaskController.Tasks(taskIdx).TaskSpec.Script.Observation.Duration);

                        case 'Time'
                            if strcmp(app.TaskController.Tasks(taskIdx).Status, 'Concluída')
                                ui.Dialog(app.UIFigure, 'warning', 'Uma tarefa no estado "Concluída" somente poderá ser executada novamente se o tipo do período de observação for "Duração" ou "Quantidade específica de amostras".');
                                return
                            end

                        case 'Samples'
                            app.TaskController.Tasks(taskIdx).Timing.startedAt = timestamp;
                            app.TaskController.Tasks(taskIdx).Timing.endedAt   = NaT;
                    end

                    app.TaskController.Tasks(taskIdx).Status = 'Na fila';
                    app.TaskController.Tasks(taskIdx).LogEntries(end+1) = struct('level', 'task', 'timestamp', char(timestamp), 'message', 'Reincluída na fila a tarefa.');

                    resetTaskBands(app.TaskController, taskIdx, 1)
                    taskSchedulerTimerFcn(app)

                case 'Na fila'
                    ui.Dialog(app.UIFigure, 'warning', 'A tarefa já está na fila de execução.');
            end
        end

        %-----------------------------------------------------------------%
        function stopTask(app, taskIdx)
            if strcmp(app.TaskController.Tasks(taskIdx).Status, 'Em andamento')
                updateTaskStatus(app.TaskController, taskIdx, 'cancellationRequested');
            end
        end

        %-----------------------------------------------------------------%
        function editTask(app, taskIdx)
            if ismember(app.TaskController.Tasks(taskIdx).Status, {'Na fila', 'Em andamento'})
                ui.Dialog(app.UIFigure, 'warning', 'Uma tarefa no estado "Na fila" ou "Em andamento" não poderá ser editada.');
                return
            end

            [isOpen, hApp] = checkStatusModule(app.tabGroupController, 'TASK_ADD');
            if isOpen && ~(strcmp(hApp.infoEdition.type, 'edit') && hApp.infoEdition.idx == taskIdx)
                ui.Dialog(app.UIFigure, 'warning', 'O módulo "TASK_ADD" já está aberto. Conclua ou feche a inclusão/edição em andamento antes de editar outra tarefa.');
                return
            end

            app.Tab4Button.Value = true;
            openModule(app.tabGroupController, app.Tab4Button, false, app.General, app, struct('type', 'edit', 'idx', taskIdx))
        end

        %-----------------------------------------------------------------%
        function deleteTask(app, taskIdx)
            if strcmp(app.TaskController.Tasks(taskIdx).Status, 'Em andamento')
                ui.Dialog(app.UIFigure, 'warning', 'A tarefa precisa ser interrompida antes da tentativa de exclusão.');
                return
            end

            if all(~strcmp({app.TaskController.Tasks.Status}, 'Em andamento')) && app.TaskController.IsRunning
                app.TaskController.IsRunning = false;
            end

            if ~app.TaskController.IsRunning
                app.TaskController.Tasks(taskIdx) = [];

                % A exclusão reordena os índices da lista, invalidando a inclusão/edição em andamento.
                if checkStatusModule(app.tabGroupController, 'TASK_ADD')
                    closeModule(app.tabGroupController, 'TASK_ADD', app.General)
                end

                refreshTaskTable(app)
            else
                ui.Dialog(app.UIFigure, 'warning', 'Uma tarefa poderá ser excluída, sendo eliminada da lista de tarefas, somente se não estiver sendo executada nenhuma tarefa.');
            end
        end

        %-----------------------------------------------------------------%
        function showTaskLog(app, taskIdx)
            log = util.HtmlTextGenerator.LOG(app.TaskController.Tasks, taskIdx);
            ui.Dialog(app.UIFigure, 'warning', log);
        end


        %-----------------------------------------------------------------%
        % ## PLOT CONTROLLER ##
        %-----------------------------------------------------------------%
        function resetPlotState(app)
            cla(app.UIAxes1)
            cla(app.UIAxes2)
            
            resetRestoreView(app)

            app.PlotHandles = struct( ...
                'ClearWrite', [], ...
                'MinHold', [], ...
                'Average', [], ...
                'MaxHold', [], ...
                'PeakExcursion', [], ...
                'Waterfall', [] ...
            );          
        end

        %-----------------------------------------------------------------%
        function resetRestoreView(app)
            app.RestoreView(1) = struct('Id', 'app.UIAxes1', 'XLim', [0, 1], 'YLim', [0, 1], 'CLim', 'auto');
            app.RestoreView(2) = struct('Id', 'app.UIAxes2', 'XLim', [0, 1], 'YLim', [0, 1], 'CLim', 'auto');
        end

        %-----------------------------------------------------------------%
        function updatePlotLayout(app)
            if app.axesTool_Waterfall.UserData.status
                set(findobj(app.UIAxes1), 'Visible', true)
                
                app.UIAxes1.Layout.Tile = 1;
                app.UIAxes1.Layout.TileSpan = [1 1];
                app.UIAxes1.XTickLabel = {};
                xlabel(app.UIAxes1, '')

                set(findobj(app.UIAxes2), 'Visible', true)
                app.UIAxes2.Layout.Tile = 2;
                app.UIAxes2.Layout.TileSpan = [2 1];

            else
                set(findobj(app.UIAxes1), 'Visible', true)

                app.UIAxes1.Layout.Tile = 1;
                app.UIAxes1.Layout.TileSpan = [3 1];
                app.UIAxes1.XTickLabelMode = 'auto';
                xlabel(app.UIAxes1, 'Frequência (MHz)')
                
                set(findobj(app.UIAxes2), 'Visible', false)
                app.UIAxes2.Layout.Tile = 4;
                app.UIAxes2.Layout.TileSpan = [1 1];
            end

            cb = findobj(app.UIAxes2.Parent.Children, 'Type', 'colorbar');
            if ~isempty(cb)
                cb.Visible = app.axesTool_Waterfall.UserData.status;
            end
        end

        %-----------------------------------------------------------------%
        function updatePlotSourceOptions(app, hasSelection, hasBands, taskIdx, bandIdx)
            sources = {'Nível'};

            if hasSelection && hasBands
                if contains(app.TaskController.Tasks(taskIdx).TaskSpec.Type, 'Drive-test (Level+Azimuth)')
                    sources{end+1} = 'Azimute';
                end
    
                % Se a tarefa for "Rompimento de Máscara Espectral" e o Status for 
                % maior do que zero, então o campo "Mask" será diferente de vazio.
                if ~isempty(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask)
                    sources{end+1} = 'Máscara';
                end
            end

            set(app.axesTool_PlotSource, 'Items', sources, 'Enable', numel(sources) > 1)
            updateToolbar(app)
        end

        %-----------------------------------------------------------------%
        function [xArray, downYLim, upYLim, freqStart, freqStop, levelUnit, strUnit] = getPlotParameters(app, taskIdx, bandIdx, newArray)
            % xArray
            freqStart = app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).FreqStart / 1e+6;
            freqStop  = app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).FreqStop  / 1e+6;
            levelUnit = app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).instrLevelUnit;
            xArray    = linspace(freqStart, freqStop, app.TaskController.Tasks(taskIdx).Bands(bandIdx).DataPoints);
    
            % General settings
            [~, strUnit] = class.Constants.yAxisUpLimit(app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).instrLevelUnit);
    
            [downYLim, upYLim] = bounds(newArray);
            downYLim  = downYLim - mod(downYLim, 10);
            upYLim    = upYLim + 10 - mod(upYLim, 10);        
            diffArray = upYLim - downYLim;
    
            if diffArray < class.Constants.yMinLimRange
                upYLim = downYLim + class.Constants.yMinLimRange;        
            elseif diffArray > class.Constants.yMaxLimRange
                downYLim = upYLim - class.Constants.yMaxLimRange;
            end
        end

        %-----------------------------------------------------------------%
        function updatePlot(app, taskIdx, bandIdx)
            waterfallIdx = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall.idx;
            newArray = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall.Matrix(waterfallIdx, :);

            if app.PlotStyleDirty
                app.PlotStyleDirty = false;

                cla(app.UIAxes1)
                app.PlotHandles.ClearWrite = [];
            end
        
            if isempty(app.PlotHandles.ClearWrite)
                [xArray, downYLim, upYLim, freqStart, freqStop, LevelUnit, strUnit] = getPlotParameters(app, taskIdx, bandIdx, newArray);

                switch app.axesTool_PlotSource.Value
                    case 'Nível'
                        % ORDINARY PLOT (SPECTRUM + MASK THRESHOLD)
                        ylabel(app.UIAxes1, sprintf('Nível (%s)', strUnit));
                        set(app.UIAxes1, XLim=[freqStart, freqStop], YLim=[downYLim, upYLim], YScale='linear')
            
                        % Mask threshold
                        if ~isempty(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask)
                            plot.draw2D.mask(app.UIAxes1, app.TaskController.Tasks(taskIdx), bandIdx)
                        end
                
                        % ClearWrite, MinHold, Average and MaxHold
                        app.PlotHandles.ClearWrite = plot.draw2D.clearWrite(app.UIAxes1, xArray, newArray, LevelUnit, 'ClrWrite', app.General);
                        
                        if app.axesTool_MinHold.UserData.status
                            app.PlotHandles.MinHold  = plot.draw2D.minHold(app.UIAxes1, app.TaskController.Tasks(taskIdx), bandIdx, xArray, newArray, LevelUnit, app.General);
                        end
                
                        if app.axesTool_Average.UserData.status
                            app.PlotHandles.Average  = plot.draw2D.average(app.UIAxes1, app.TaskController.Tasks(taskIdx), bandIdx, xArray, newArray, LevelUnit, app.General);
                        end
                
                        if app.axesTool_MaxHold.UserData.status
                            app.PlotHandles.MaxHold  = plot.draw2D.maxHold(app.UIAxes1, app.TaskController.Tasks(taskIdx), bandIdx, xArray, newArray, LevelUnit, app.General);
                        end
            
                        if app.axesTool_Peak.UserData.status
                            app.PlotHandles.PeakExcursion = plot.draw2D.peakExcursion(app.PlotHandles.PeakExcursion, app.PlotHandles.ClearWrite, app.TaskController.Tasks(taskIdx), bandIdx, newArray);
                        end

                    case 'Azimute'
                        ylabel(app.UIAxes1, 'Azimute (º)');
                        set(app.UIAxes1, XLim=[freqStart, freqStop], YLim=[0, 360], YScale='linear')

                        app.PlotHandles.ClearWrite = plot.draw2D.clearWrite(app.UIAxes1, xArray, app.TaskController.Tasks(taskIdx).Bands(bandIdx).AzimuthTrace, LevelUnit, 'ClrWrite', app.General, 'Marker', '.', 'MarkerSize', 12, 'LineStyle', 'none');

                    case 'Máscara'
                        ylabel(app.UIAxes1, 'Rompimento (%)');
                        set(app.UIAxes1, XLim=[freqStart, freqStop], YLim=[.1, 100], YScale='log')
            
                        KK = 100/app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ValidationCount;
                        app.PlotHandles.ClearWrite = plot.draw2D.clearWrite(app.UIAxes1, xArray, KK.*app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ViolationsPerBin, '%%', 'MaskPlot', app.General, 'Marker', '.', 'MarkerSize', 12, 'LineStyle', 'none');
                end
                
                app.RestoreView(1).XLim = app.UIAxes1.XLim;
                app.RestoreView(1).YLim = app.UIAxes1.YLim;

            else
                switch app.axesTool_PlotSource.Value
                    case 'Nível'
                        plot.draw2D.update(app.PlotHandles.ClearWrite, newArray, app.General)
                        
                        if ~isempty(app.PlotHandles.MinHold)
                            plot.draw2D.update(app.PlotHandles.MinHold, newArray, app.General)
                        end
                        
                        if ~isempty(app.PlotHandles.Average)
                            plot.draw2D.update(app.PlotHandles.Average, newArray, app.General)
                        end
                        
                        if ~isempty(app.PlotHandles.MaxHold)
                            plot.draw2D.update(app.PlotHandles.MaxHold, newArray, app.General)
                        end
    
                        if ~isempty(app.PlotHandles.PeakExcursion)
                            app.PlotHandles.PeakExcursion = plot.draw2D.peakExcursion(app.PlotHandles.PeakExcursion, app.PlotHandles.ClearWrite, app.TaskController.Tasks(taskIdx), bandIdx, newArray);
                        end

                    case 'Azimute'
                        plot.draw2D.update(app.PlotHandles.ClearWrite, app.TaskController.Tasks(taskIdx).Bands(bandIdx).AzimuthTrace, app.General)

                    case 'Máscara'
                        KK = 100/app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ValidationCount;
                        plot.draw2D.update(app.PlotHandles.ClearWrite, KK.*app.TaskController.Tasks(taskIdx).Bands(bandIdx).Mask.ViolationsPerBin, app.General)
                end
            end

            % Waterfall
            if app.axesTool_Waterfall.UserData.status
                if isempty(app.PlotHandles.Waterfall)
                    if ~exist('xArray', 'var')
                        [xArray, downYLim, upYLim, freqStart, freqStop] = getPlotParameters(app, taskIdx, bandIdx, newArray);
                    end
                    set(app.UIAxes2, 'YLim', [1, app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall.Depth], 'View', [0, 90], 'CLim', [downYLim, upYLim])

                    app.RestoreView(2).XLim = [freqStart, freqStop];
                    app.RestoreView(2).YLim = app.UIAxes2.YLim;
                    app.RestoreView(2).CLim = app.UIAxes2.CLim;

                    app.PlotHandles.Waterfall = plot.draw3D.waterfall(app.UIAxes2, app.TaskController.Tasks(taskIdx), bandIdx, xArray);
                else
                    app.PlotHandles.Waterfall.CData = circshift(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall.Matrix, -waterfallIdx);
                end
            end
        end

        %-----------------------------------------------------------------%
        function updateToolbar(app)
            isLevel = strcmp(app.axesTool_PlotSource.Value, 'Nível');

            set([ ...
                app.axesTool_MinHold, ...
                app.axesTool_Average, ...
                app.axesTool_MaxHold, ...
                app.axesTool_Peak ...
            ], 'Enable', isLevel)
        end


        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO E CICLO DE VIDA
        %-----------------------------------------------------------------%
        function initializeAxes(app)
            axesContainer = tiledlayout(app.AxesContainer, 3, 1, "Padding", "compact", "TileSpacing", "compact");

            app.UIAxes1 = plot.axesCreation(axesContainer, 'Cartesian', {'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [3,1];
            
            app.UIAxes2 = plot.axesCreation(axesContainer, 'Cartesian', {'Visible', 0, 'Layer', 'top', 'Box', 'on', 'XGrid', 'off', 'XMinorGrid', 'off', 'YGrid', 'off', 'YMinorGrid', 'off', 'UserData', struct('CLimMode', 'auto', 'Colormap', '')});
            app.UIAxes2.Layout.Tile = 4;

            colormap(app.UIAxes2, app.General.plot.waterfall.Colormap);
            plot.axesColorbar(app.UIAxes2, "eastoutside", {'Visible', false})

            xlabel(app.UIAxes1, 'Frequência (MHz)')
            ylabel(app.UIAxes1, 'Nível (dB)')
            ysecondarylabel(app.UIAxes1, sprintf('\n\n'))
            
            xlabel(app.UIAxes2, 'Frequência (MHz)')
            ylabel(app.UIAxes2, 'Amostras')

            linkaxes([app.UIAxes1, app.UIAxes2], 'x')

            plot.axesInteractivity.DefaultCreation([app.UIAxes1, app.UIAxes2], [dataTipInteraction, regionZoomInteraction, rulerPanInteraction])
        end

        %-----------------------------------------------------------------%
        function restoreTasksFromFile(app)
            [~, programDataFolder] = appEngine.util.Path(class.Constants.appName, app.rootFolder);
            if isfile(fullfile(programDataFolder, 'taskListState.mat'))
                app.progressDialog.Visible = 'visible';

                load(fullfile(programDataFolder, 'taskListState.mat'), 'tasks');

                % É possível que o MATLAB não consiga instancionar o objeto
                % "model.Task", lendo-o como "uint32", o que inviabiliza 
                % o aproveitamento da informação salva...

                % Warning: Variable 'Tasks' originally saved as a model.Task cannot be instantiated as an object and will be read in as a uint32.

                if exist('tasks', 'var') && isa(tasks, 'model.Task') && ~isempty(tasks)
                    for ii = 1:numel(tasks)
                        tasks(ii) = initializeTaskReceiver(tasks(ii));
                        tasks(ii) = resolveStreamingHandle(app.TaskController, tasks(ii));
                        tasks(ii) = resolveGpsHandle(app.TaskController, tasks(ii));

                        if ismember(tasks(ii).Status, {'Na fila', 'Em andamento'})
                            tasks(ii).Status = 'Erro';
                        end
                    end

                    app.TaskController.Tasks = tasks;
                    refreshTaskTable(app)

                    % Ida ao modo de "Execução das tarefas da monitoração"
                    % de forma programática:
                    app.Tab1Button.Value = 1;
                    onTabNavigatorButtonPushed(app, struct('Source', app.Tab1Button, 'PreviousValue', 0))
                end

                app.progressDialog.Visible = 'hidden';
            end

            function [task, msgError] = initializeTaskReceiver(task)
                % Função quase idêntica a model.Receiver.testConnectivity.
                % Uso de informação constante no objeto "model.Task" ao
                % invés da constante em arquivo "InstrumentList.json".
    
                receiver = buildReceiverConfig(task);
                [idx, msgError] = connect(app.receiverObj, receiver);
                
                if isempty(msgError)
                    task.TaskSpec.Receiver.Handle = app.receiverObj.Table.Handle{idx};
                    task.Connections.receiver = task.TaskSpec.Receiver.Handle;
                end
            end
        end

        %-----------------------------------------------------------------%
        function saveTasksToFile(app)
            tasks = copy(app.TaskController.Tasks);
            
            for ii = 1:numel(tasks)
                tasks(ii).Connections.receiver = [];
                tasks(ii).Connections.stream = [];
                tasks(ii).Connections.gps = [];

                tasks(ii).TaskSpec.Receiver.Handle = [];
                tasks(ii).TaskSpec.Streaming.Handle = [];
                tasks(ii).TaskSpec.GPS.Handle = [];
            end

            [~, programDataFolder] = appEngine.util.Path(class.Constants.appName, app.rootFolder);
            save(fullfile(programDataFolder, 'taskListState.mat'), 'tasks')
        end

        %-----------------------------------------------------------------%
        % TIMER 
        %-----------------------------------------------------------------%
        function createTaskSchedulerTimer(app)
            app.TaskSchedulerTimer = timer("ExecutionMode", "fixedRate", "Period", 10, "TimerFcn", @(~,~) taskSchedulerTimerFcn(app));
            start(app.TaskSchedulerTimer)
        end

        %-----------------------------------------------------------------%
        function taskSchedulerTimerFcn(app)
            if ~app.TaskController.IsRunning
                runLoop(app.TaskController)
            end

            % Validação que garante sincronismo entre tarefas e a sua
            % disposição na tabela da GUI.
            if numel(app.TaskController.Tasks) ~= height(app.UITable.Data)
                refreshTaskTable(app, app.UITable.Selection)
            end
        end


        %-----------------------------------------------------------------%
        % REGISTRO DOS LISTENERS E SEUS CALLBACKS
        %-----------------------------------------------------------------%
        function registerTaskControllerListeners(app)
            % Inscreve o app nos eventos disparados por "app.TaskController"
            % (model.TaskController), substituindo as antigas chamadas diretas
            % a métodos de interface dentro do loop de monitoração (antigo
            % "RegularTask_MainLoop" e demais funções "RegularTask_*" que
            % foram migradas para model.TaskController).

            addlistener(app.TaskController, 'StatusChanged',      @(~,~)   refreshTaskTable(app, app.UITable.Selection));
            addlistener(app.TaskController, 'TasksChanged',       @(~,~)   saveTasksToFile(app));
            addlistener(app.TaskController, 'BandDataAcquired',   @(~,evt) onTaskBandDataAcquired(evt));
            addlistener(app.TaskController, 'GpsUpdated',         @(~,evt) onTaskGpsUpdated(evt));
            addlistener(app.TaskController, 'ErrorRaised',        @(~,evt) onTaskErrorRaised(evt));
            addlistener(app.TaskController, 'RevisitInfoChanged', @(~,~)   updateTaskMetaData(app));

            function onTaskBandDataAcquired(evt)
                taskIdx = evt.TaskId;
                bandIdx = evt.BandId;
                maskTrigger = evt.Payload;
    
                if app.UITable.Selection == taskIdx
                    updateErrorCount(app, taskIdx)
    
                    if app.SpectrumFlowList.Value == bandIdx
                        updatePlot(app, taskIdx, bandIdx)
                        updateMaskStatus(app, maskTrigger, taskIdx, bandIdx)
                        updateRevisitTimeInfo(app, taskIdx, bandIdx)
                        app.Sweeps.Text = string(app.TaskController.Tasks(taskIdx).Bands(bandIdx).OutputFile.WritedSamples);
                        drawnow
                    end
                end
            end
    
            function onTaskGpsUpdated(evt)
                taskIdx = evt.TaskId;
                gpsData = evt.Payload;
    
                % As coordenadas da estação - registradas em app.General.context.CONFIG.station
                % - são atualizadas apenas se a estação for do tipo móvel ("Mobile") 
                % e as novas coordenadas geográficas forem válidas.
                if strcmp(app.General.context.CONFIG.station.type, 'Mobile') && gpsData.Status
                    app.General.context.CONFIG.station.latitude  = gpsData.Latitude;
                    app.General.context.CONFIG.station.longitude = gpsData.Longitude;
                end
    
                if app.UITable.Selection == taskIdx
                    updateGPSStatus(app, gpsData)
                end
            end

            function onTaskErrorRaised(evt)
                taskIdx = evt.TaskId;
    
                if app.UITable.Selection == taskIdx
                    updateErrorCount(app, taskIdx)
                end
                beep
            end
        end


        %-----------------------------------------------------------------%
        % MISCELÂNEAS
        %-----------------------------------------------------------------%
        function updateWarningLampVisibility(app)
            if isfolder(app.General.fileFolder.DataHub_POST)
                app.DataHubLamp.Visible = 0;
            else
                app.DataHubLamp.Visible = 1;
            end
        end

        %-----------------------------------------------------------------%
        function updateLastVisitedFolder(app, filePath)
            app.General_I.fileFolder.lastVisited = filePath;
            app.General.fileFolder.lastVisited   = filePath;

            appEngine.util.generalSettingsSave(class.Constants.appName, app.rootFolder, app.General_I, app.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                appEngine.boot(app, app.Role)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if strcmp(app.progressDialog.Visible, 'visible')
                app.progressDialog.Visible = 'hidden';
                return
            end

            if app.TaskController.IsRunning
                ui.Dialog(app.UIFigure, 'warning', 'Existe uma tarefa em execução...');
                return
            end

            if ~strcmp(app.executionMode, 'webApp')
                questionMsg = 'Deseja fechar o aplicativo?';
                userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', questionMsg, {'Sim', 'Não'}, 1, 2);
                if userSelection == "Não"
                    return
                end
            end

            if app.General.context.CONFIG.restoreLastSession
                saveTasksToFile(app)
            else
                [~, programDataFolder] = appEngine.util.Path(class.Constants.appName, app.rootFolder);
                if isfile(fullfile(programDataFolder, 'taskListState.mat'))
                    delete(fullfile(programDataFolder, 'taskListState.mat'))
                end
            end

            if app.General.context.CONFIG.station.type == "Mobile"
                appEngine.util.generalSettingsSave(class.Constants.appName, app.rootFolder, app.General_I, app.executionMode)
            end

            if ~isempty(app.tcpServer)
                delete(app.tcpServer.Server)
            end

            % Aspectos gerais (carregar em todos os apps):
            appEngine.beforeDeleteApp(app.progressDialog, app.General_I.fileFolder.tempPath, app.tabGroupController, app.executionMode)
            delete(app)
            
        end

        % Callback function: AppInfo, FigurePosition, Tab1Button, 
        % ...and 5 other components
        function onTabNavigatorButtonPushed(app, event)

            switch event.Source
                case {app.Tab1Button, app.Tab2Button, app.Tab3Button, app.Tab4Button, app.Tab5Button, app.Tab6Button}
                    openModule(app.tabGroupController, event.Source, event.PreviousValue, app.General, app)

                case app.FigurePosition
                    app.UIFigure.Position(3:4) = class.Constants.windowSize;
                    appEngine.util.setWindowPosition(app.UIFigure)
                    focus(findobj(app.NavBar.Children, 'Type', 'uistatebutton', 'Value', true))

                case app.AppInfo
                    appInfo = util.HtmlTextGenerator.AppInfo( ...
                        app.General, ...
                        app.rootFolder, ...
                        app.executionMode, ...
                        app.renderCount, ...
                        "popup" ...
                    );
                    ui.Dialog(app.UIFigure, 'info', appInfo);
            end
            
        end

        % Selection changed function: UITable
        function onTaskSelectionChanged(app, event)

            if isempty(event.Selection)
                if ~isempty(event.PreviousSelection)
                    app.UITable.Selection = event.PreviousSelection;
                end

                return
            end

            loadSelectedTask(app, 1)
            
        end

        % Value changed function: SpectrumFlowList
        function onBandSelectionChanged(app, event)

            try
                taskIdx = app.UITable.Selection;
                bandIdx = app.SpectrumFlowList.Value;
                loadSelectedBand(app, taskIdx, bandIdx)

            catch ME
                if exist('event', 'var')
                    event.Source.Value = event.Source.PreviousValue;
                    onBandSelectionChanged(app)
                end

                ui.Dialog(app.UIFigure, 'error', getReport(ME));
            end
            drawnow

        end

        % Image clicked function: axesTool_RestoreView
        function onAxesToolbarRestoreViewImageClicked(app, event)
            
            set(app.UIAxes1, 'XLim', app.RestoreView(1).XLim, 'YLim', app.RestoreView(1).YLim)
            set(app.UIAxes2, 'XLim', app.RestoreView(2).XLim, 'YLim', app.RestoreView(2).YLim)

        end

        % Image clicked function: axesTool_ExportGraphics
        function onAxesToolbarExportGraphicsClicked(app, event)
            
            fileFormats = {'*.jpeg', '(*.jpeg)'};
            fileFullPath = ui.Dialog(app.UIFigure, 'uiputfile', '', fileFormats, app.General.fileFolder.userPath);
            if isempty(fileFullPath)
                return
            end

            app.progressDialog.Visible = 'visible';

            try
                exportgraphics(app.AxesContainer, fileFullPath, 'ContentType', 'image', 'Resolution', app.General.reportLib.image.resolutionDpi, 'BackgroundColor', [0,0,0])
            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Value changed function: axesTool_PlotSource
        function onAxesToolbarPlotSourceImageClicked(app, event)
            
            taskIdx = app.UITable.Selection;
            bandIdx = app.SpectrumFlowList.Value;

            if isempty(app.TaskController.Tasks(taskIdx).Bands)
                return
            end
            
            if ~isempty(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall)
                waterfallIdx = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall.idx;

                if waterfallIdx
                    app.PlotStyleDirty = true;
                    updatePlot(app, taskIdx, bandIdx)
                end
            end

            updateToolbar(app)
            
        end

        % Image clicked function: axesTool_Average, axesTool_MaxHold, 
        % ...and 2 other components
        function onAxesToolbarTraceModeImageClicked(app, event)
            
            event.Source.UserData.status = ~event.Source.UserData.status;
            if isfield(event.Source.UserData, 'icon')
                if event.Source.UserData.status
                    event.Source.ImageSource = event.Source.UserData.icon.On;
                else
                    event.Source.ImageSource = event.Source.UserData.icon.Off;
                end
            end

            if isempty(app.UITable.Selection) || isempty(app.SpectrumFlowList.Items) || strcmp(app.axesTool_PlotSource.Value, 'Máscara')
                return
            end

            taskIdx = app.UITable.Selection;
            bandIdx = app.SpectrumFlowList.Value;

            if isempty(app.TaskController.Tasks(taskIdx).Bands)
                return
            end

            if ~isempty(app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall)
                waterfallIdx = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall.idx;

                if waterfallIdx
                    freqStart = app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).FreqStart / 1e+6;
                    freqStop  = app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).FreqStop  / 1e+6;
                    levelUnit = app.TaskController.Tasks(taskIdx).TaskSpec.Script.Band(bandIdx).instrLevelUnit;

                    xArray    = linspace(freqStart, freqStop, app.TaskController.Tasks(taskIdx).Bands(bandIdx).DataPoints);
                    newArray  = app.TaskController.Tasks(taskIdx).Bands(bandIdx).Waterfall.Matrix(waterfallIdx, :);

                    switch event.Source
                        case app.axesTool_MinHold
                            if event.Source.UserData.status
                                app.PlotHandles.MinHold = plot.draw2D.minHold(app.UIAxes1, app.TaskController.Tasks(taskIdx), bandIdx, xArray, newArray, levelUnit, app.General);
                            else
                                delete(app.PlotHandles.MinHold)
                                app.PlotHandles.MinHold = [];
                            end

                        case app.axesTool_Average
                            if event.Source.UserData.status
                                app.PlotHandles.Average = plot.draw2D.average(app.UIAxes1, app.TaskController.Tasks(taskIdx), bandIdx, xArray, newArray, levelUnit, app.General);
                            else
                                delete(app.PlotHandles.Average)
                                app.PlotHandles.Average = [];
                            end

                        case app.axesTool_MaxHold
                            if event.Source.UserData.status
                                app.PlotHandles.MaxHold = plot.draw2D.maxHold(app.UIAxes1, app.TaskController.Tasks(taskIdx), bandIdx, xArray, newArray, levelUnit, app.General);
                            else
                                delete(app.PlotHandles.MaxHold)
                                app.PlotHandles.MaxHold = [];
                            end

                        case app.axesTool_Peak
                            if event.Source.UserData.status
                                app.PlotHandles.PeakExcursion = plot.draw2D.peakExcursion(app.PlotHandles.PeakExcursion, app.PlotHandles.ClearWrite, app.TaskController.Tasks(taskIdx), bandIdx, newArray);
                            else
                                delete(app.PlotHandles.PeakExcursion)
                                app.PlotHandles.PeakExcursion = [];
                            end
                    end
                    drawnow
                end
            end

        end

        % Image clicked function: axesTool_Waterfall
        function onAxesToolbarShowWaterfallImageClicked(app, event)
            
            event.Source.UserData.status = ~event.Source.UserData.status;
            updatePlotLayout(app)

            if ~isempty(app.UITable.Selection) && ~app.TaskController.IsRunning
                onAxesToolbarPlotSourceImageClicked(app)
            end

        end

        % Image clicked function: PanelLeftVisibility, TableVisibility
        function onToolbarButtonToogleVisibility(app, event)
            
            switch event.Source
                case app.PanelLeftVisibility
                    app.PanelLeftVisibility.UserData.status = ~app.PanelLeftVisibility.UserData.status;
                    if app.PanelLeftVisibility.UserData.status
                        app.PanelLeftVisibility.ImageSource = 'layout-sidebar-left.svg';
                        app.Document.ColumnWidth(1:2) = {320,10};
                    else
                        app.PanelLeftVisibility.ImageSource = 'layout-sidebar-left-off.svg';
                        app.Document.ColumnWidth(1:2) = {0,0};
                    end

                case app.TableVisibility
                    app.TableVisibility.UserData.status = ~app.TableVisibility.UserData.status;
                    if app.TableVisibility.UserData.status
                        app.Document.RowHeight(1:2) = {144,10};
                        app.UITable.Visible = 'on';
                    else
                        app.Document.RowHeight(1:2) = {0,0};
                        app.UITable.Visible = 'off';
                    end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'icon_32.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1_Task = uitab(app.TabGroup);
            app.Tab1_Task.Title = 'TASK_VIEW';
            app.Tab1Grid = uigridlayout(app.Tab1_Task);
            app.Document = uigridlayout(app.Tab1Grid);
            app.UITable = uitable(app.Document);
            app.UITable.ColumnName = {'TAREFA'; 'RECEPTOR'; 'INCLUSÃO'; 'INÍCIO|OBSERVAÇÃO'; 'FIM|OBSERVAÇÃO'; 'ESTADO'; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true false];
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @onTaskSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.SpectrumFlowList = uidropdown(app.Document);
            app.SpectrumFlowList.Items = {};
            app.SpectrumFlowList.ValueChangedFcn = createCallbackFcn(app, @onBandSelectionChanged, true);
            app.SpectrumFlowList.Value = {};
            app.MetaData = uilabel(app.Document);
            app.MetaData.Interpreter = 'html';
            app.MetaData.Text = '';
            app.AxesContainer = uipanel(app.Document);
            app.AxesToolbar = uigridlayout(app.Document);
            app.axesTool_RestoreView = uiimage(app.AxesToolbar);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarRestoreViewImageClicked, true);
            app.axesTool_RestoreView.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'Home_18.png');
            app.axesTool_ExportGraphics = uiimage(app.AxesToolbar);
            app.axesTool_ExportGraphics.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarExportGraphicsClicked, true);
            app.axesTool_ExportGraphics.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'screen-cut.svg');
            app.axesTool_PlotSource = uidropdown(app.AxesToolbar);
            app.axesTool_PlotSource.Items = {'Nível'};
            app.axesTool_PlotSource.ValueChangedFcn = createCallbackFcn(app, @onAxesToolbarPlotSourceImageClicked, true);
            app.axesTool_PlotSource.Enable = 'off';
            app.axesTool_PlotSource.Value = 'Nível';
            app.axesTool_MinHold = uiimage(app.AxesToolbar);
            app.axesTool_MinHold.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarTraceModeImageClicked, true);
            app.axesTool_MinHold.Enable = 'off';
            app.axesTool_MinHold.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'MinHold_32.png');
            app.axesTool_Average = uiimage(app.AxesToolbar);
            app.axesTool_Average.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarTraceModeImageClicked, true);
            app.axesTool_Average.Enable = 'off';
            app.axesTool_Average.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'Average_32.png');
            app.axesTool_MaxHold = uiimage(app.AxesToolbar);
            app.axesTool_MaxHold.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarTraceModeImageClicked, true);
            app.axesTool_MaxHold.Enable = 'off';
            app.axesTool_MaxHold.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'MaxHold_32.png');
            app.axesTool_Peak = uiimage(app.AxesToolbar);
            app.axesTool_Peak.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarTraceModeImageClicked, true);
            app.axesTool_Peak.Enable = 'off';
            app.axesTool_Peak.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'Detection_18.png');
            app.axesTool_Waterfall = uiimage(app.AxesToolbar);
            app.axesTool_Waterfall.ImageClickedFcn = createCallbackFcn(app, @onAxesToolbarShowWaterfallImageClicked, true);
            app.axesTool_Waterfall.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'Waterfall_24.png');
            app.TaskStatusGrid = uigridlayout(app.Document);
            app.SweepsPanel = uipanel(app.TaskStatusGrid);
            app.SweepsGrid = uigridlayout(app.SweepsPanel);
            app.SweepsLabel = uilabel(app.SweepsGrid);
            app.SweepsLabel.Text = {'VARREDURAS'; 'EM ARQUIVO'};
            app.Sweeps = uilabel(app.SweepsGrid);
            app.Sweeps.Text = '-1';
            app.RecordingIcon = uiimage(app.SweepsGrid);
            app.RecordingIcon.Visible = 'off';
            app.RecordingIcon.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'REC.gif');
            app.ReceiverErrorCount = uilabel(app.SweepsGrid);
            app.ReceiverErrorCount.Visible = 'off';
            app.ReceiverErrorCount.Text = '0';
            app.ReceiverErrorCountIcon = uiimage(app.SweepsGrid);
            app.ReceiverErrorCountIcon.Visible = 'off';
            app.ReceiverErrorCountIcon.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'Warn_18.png');
            app.MaskPanel = uipanel(app.TaskStatusGrid);
            app.MaskGrid = uigridlayout(app.MaskPanel);
            app.MaskLabel = uilabel(app.MaskGrid);
            app.MaskLabel.Text = 'MÁSCARA';
            app.MaskStatus = uilabel(app.MaskGrid);
            app.MaskStatus.Enable = 'off';
            app.MaskStatus.Interpreter = 'html';
            app.MaskStatus.Text = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.0 kHz '; 'Ʌ -1.0 dB </font>'; 'dd-mmm-yyyy '; 'HH:MM:SS '};
            app.GPSLastFixPanel = uipanel(app.TaskStatusGrid);
            app.GPSLastFixGrid = uigridlayout(app.GPSLastFixPanel);
            app.GPSLastFixLabel = uilabel(app.GPSLastFixGrid);
            app.GPSLastFixLabel.Text = {'COORDENADAS'; 'GEOGRÁFICAS'};
            app.GPSLastFixIconGrid = uigridlayout(app.GPSLastFixGrid);
            app.GPSLastFixIcon = uilamp(app.GPSLastFixIconGrid);
            app.GPSLastFix = uilabel(app.GPSLastFixGrid);
            app.GPSLastFix.Interpreter = 'html';
            app.GPSLastFix.Text = {'<b style="color: #a2142f; font-size: 14;">-1.000</b> LAT '; '<b style="color: #a2142f; font-size: 14;">-1.000</b> LON '; 'dd-mmm-yyyy '; 'HH:MM:SS '};
            app.GPSErrorCount = uilabel(app.GPSLastFixGrid);
            app.GPSErrorCount.Visible = 'off';
            app.GPSErrorCount.Text = '0';
            app.GPSErrorCountIcon = uiimage(app.GPSLastFixGrid);
            app.GPSErrorCountIcon.Visible = 'off';
            app.GPSErrorCountIcon.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'Warn_18.png');
            app.Toolbar = uigridlayout(app.Tab1Grid);
            app.PanelLeftVisibility = uiimage(app.Toolbar);
            app.PanelLeftVisibility.ImageClickedFcn = createCallbackFcn(app, @onToolbarButtonToogleVisibility, true);
            app.PanelLeftVisibility.ImageSource = 'layout-sidebar-left.svg';
            app.TableVisibility = uiimage(app.Toolbar);
            app.TableVisibility.ImageClickedFcn = createCallbackFcn(app, @onToolbarButtonToogleVisibility, true);
            app.TableVisibility.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'View_16.png');
            app.SelectedTaskSeparator = uiimage(app.Toolbar);
            app.SelectedTaskSeparator.Enable = 'off';
            app.SelectedTaskSeparator.Visible = 'off';
            app.SelectedTaskSeparator.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'LineV.svg');
            app.SelectedTaskInfo = uilabel(app.Toolbar);
            app.SelectedTaskInfo.Interpreter = 'html';
            app.SelectedTaskInfo.Text = '';
            app.RevisitTimeInfo = uilabel(app.Toolbar);
            app.RevisitTimeInfo.Text = '';
            app.Tab2_InstrumentList = uitab(app.TabGroup);
            app.Tab2_InstrumentList.Title = 'INSTRUMENT';
            app.Tab3_TaskEdition = uitab(app.TabGroup);
            app.Tab3_TaskEdition.Title = 'TASK_EDIT';
            app.Tab4_TaskAdd = uitab(app.TabGroup);
            app.Tab4_TaskAdd.Title = 'TASK_ADD';
            app.Tab5_Server = uitab(app.TabGroup);
            app.Tab5_Server.Title = 'SERVER';
            app.Tab6_Config = uitab(app.TabGroup);
            app.Tab6_Config.Title = 'CONFIG';
            app.NavBar = uigridlayout(app.GridLayout);
            app.AppName = uilabel(app.NavBar);
            app.AppName.Interpreter = 'html';
            app.AppName.Text = {'appColeta v. 2.00.0'; '<font style="font-size: 9px;">R2024a</font>'};
            app.Tab1Button = uibutton(app.NavBar, 'state');
            app.Tab1Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab1Button.Tag = 'TASK_VIEW';
            app.Tab1Button.Tooltip = {'Acompanha execução de tarefas'};
            app.Tab1Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'run-all-24px-yellow.svg');
            app.Tab1Button.Text = '';
            app.Tab1Button.Value = true;
            app.ButtonsSeparator1 = uiimage(app.NavBar);
            app.ButtonsSeparator1.Enable = 'off';
            app.ButtonsSeparator1.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'LineV_White.svg');
            app.Tab2Button = uibutton(app.NavBar, 'state');
            app.Tab2Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab2Button.Tag = 'INSTRUMENT';
            app.Tab2Button.Tooltip = {'Instrumentos'};
            app.Tab2Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'circuit-board.svg');
            app.Tab2Button.Text = '';
            app.Tab3Button = uibutton(app.NavBar, 'state');
            app.Tab3Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab3Button.Tag = 'TASK_EDIT';
            app.Tab3Button.Tooltip = {'Tarefas de monitoração'};
            app.Tab3Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'server-process.svg');
            app.Tab3Button.Text = '';
            app.Tab4Button = uibutton(app.NavBar, 'state');
            app.Tab4Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab4Button.Tag = 'TASK_ADD';
            app.Tab4Button.Tooltip = {'Inclui nova tarefa de monitoração'};
            app.Tab4Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'empty-window.svg');
            app.Tab4Button.Text = '';
            app.ButtonsSeparator2 = uiimage(app.NavBar);
            app.ButtonsSeparator2.Enable = 'off';
            app.ButtonsSeparator2.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'LineV_White.svg');
            app.Tab5Button = uibutton(app.NavBar, 'state');
            app.Tab5Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab5Button.Tag = 'SERVER';
            app.Tab5Button.Tooltip = {'Servidor de comunicação (TCP)'};
            app.Tab5Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'cloud-upload.svg');
            app.Tab5Button.Text = '';
            app.Tab6Button = uibutton(app.NavBar, 'state');
            app.Tab6Button.ValueChangedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.Tab6Button.Tag = 'CONFIG';
            app.Tab6Button.Tooltip = {'Configurações gerais'};
            app.Tab6Button.Icon = fullfile(pathToMLAPP, 'resources', 'Icons', 'gear-24px-white.svg');
            app.Tab6Button.Text = '';
            app.jsBackDoor = uihtml(app.NavBar);
            app.DataHubLamp = uiimage(app.NavBar);
            app.DataHubLamp.Visible = 'off';
            app.DataHubLamp.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'red-circle-blink.gif');
            app.FigurePosition = uiimage(app.NavBar);
            app.FigurePosition.ImageClickedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.FigurePosition.Visible = 'off';
            app.FigurePosition.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'screen-normal-24px-white.svg');
            app.AppInfo = uiimage(app.NavBar);
            app.AppInfo.ImageClickedFcn = createCallbackFcn(app, @onTabNavigatorButtonPushed, true);
            app.AppInfo.ImageSource = fullfile(pathToMLAPP, 'resources', 'Icons', 'kebab-vertical-24px-white.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAppColeta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end